classdef FwdModel_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LayerconductEditField           matlab.ui.control.NumericEditField
        LayerconductEditFieldLabel      matlab.ui.control.Label
        ObjectDropDown                  matlab.ui.control.DropDown
        ObjectDropDownLabel             matlab.ui.control.Label
        CellconductEditField            matlab.ui.control.NumericEditField
        CellconductEditFieldLabel       matlab.ui.control.Label
        LoadEIDORSButton                matlab.ui.control.Button
        SetInputDataButton              matlab.ui.control.Button
        GetInputDataButton              matlab.ui.control.Button
        PathEIDORSEditField             matlab.ui.control.EditField
        PathEIDORSEditFieldLabel        matlab.ui.control.Label
        SaveEIDORSButton                matlab.ui.control.Button
        fwd_modelnameEditField          matlab.ui.control.EditField
        fwd_modelnameEditFieldLabel     matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        FowardModelTab                  matlab.ui.container.Tab
        MeasurementChamberDesignPanel   matlab.ui.container.Panel
        chName                          matlab.ui.control.EditField
        ChamberDesignnameEditFieldLabel  matlab.ui.control.Label
        pB_chSave                       matlab.ui.control.Button
        pB_chLoad                       matlab.ui.control.Button
        electrodes                      matlab.ui.container.Panel
        electrode_layout_info           matlab.ui.control.TextArea
        pB_minus_elec                   matlab.ui.control.Button
        pB_plus_elec                    matlab.ui.control.Button
        dataElecLayout                  matlab.ui.control.Table
        BodyPanel                       matlab.ui.container.Panel
        chFem                           matlab.ui.control.NumericEditField
        FEMRefinementEditFieldLabel     matlab.ui.control.Label
        chBoxZ                          matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        chBoxY                          matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        chBoxX                          matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        chTyp                           matlab.ui.control.DropDown
        TypDropDownLabel                matlab.ui.control.Label
        ForwardModelPanel               matlab.ui.container.Panel
        pB_showmesh                     matlab.ui.control.StateButton
        fwd_modelsolvingparametersPanel  matlab.ui.container.Panel
        fmdlmiscperm_symDropDown        matlab.ui.control.DropDown
        fmdlmiscperm_symDropDownLabel   matlab.ui.control.Label
        fmdlsystem_matDropDown          matlab.ui.control.DropDown
        fmdlsystem_matDropDownLabel     matlab.ui.control.Label
        fmdljacobianDropDown            matlab.ui.control.DropDown
        fmdljacobianDropDownLabel       matlab.ui.control.Label
        fmdlsolveDropDown               matlab.ui.control.DropDown
        fmdlsolveDropDownLabel          matlab.ui.control.Label
        Save_fwd_modelButton            matlab.ui.control.Button
        Load_fwd_modelButton            matlab.ui.control.Button
        showmeshqualityCheckBox         matlab.ui.control.CheckBox
        MakeFowardModelButton           matlab.ui.control.Button
        PatternTab                      matlab.ui.container.Tab
        PatternparameterPanel           matlab.ui.container.Panel
        option_mkstimpatternTextArea    matlab.ui.control.TextArea
        option_mkstimpatternTextAreaLabel  matlab.ui.control.Label
        meas_SpecialPatternEditField    matlab.ui.control.EditField
        meas_SpecialPatternEditFieldLabel  matlab.ui.control.Label
        meas_pattern_typDropDown        matlab.ui.control.DropDown
        meas_pattern_typDropDownLabel   matlab.ui.control.Label
        Inj_SpecialPatternEditField     matlab.ui.control.EditField
        Inj_SpecialPatternEditFieldLabel  matlab.ui.control.Label
        Inj_pattern_typDropDown         matlab.ui.control.DropDown
        Inj_pattern_typDropDownLabel    matlab.ui.control.Label
        PatternGeneratorfuncDropDown    matlab.ui.control.DropDown
        PatternGeneratorfuncDropDownLabel  matlab.ui.control.Label
        AmplitudemAEditField            matlab.ui.control.NumericEditField
        AmplitudemAEditFieldLabel       matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        InjectionNrDropDown             matlab.ui.control.DropDown
        InjectionNrDropDownLabel        matlab.ui.control.Label
        alldisplayCheckBox              matlab.ui.control.CheckBox
        Own_DisplayScriptCheckBox       matlab.ui.control.CheckBox
        withmeasPatternCheckBox         matlab.ui.control.CheckBox
        AnimatedCheckBox                matlab.ui.control.CheckBox
        holdexistingpatternCheckBox     matlab.ui.control.CheckBox
        PatterncreateButton             matlab.ui.control.Button
        DisplayfmdlwithElectrodesnumberButton  matlab.ui.control.Button
        MeasPatternPanel                matlab.ui.container.Panel
        MeasPatternTable                matlab.ui.control.Table
        DisplayInjectionPatternButton   matlab.ui.control.Button
        InjectionPatternPanel           matlab.ui.container.Panel
        InjectionPatternTable           matlab.ui.control.Table
        SolvefowardTab                  matlab.ui.container.Tab
        BufferconductEditField          matlab.ui.control.NumericEditField
        BufferconductEditFieldLabel     matlab.ui.control.Label
        save_fwd_sol                    matlab.ui.control.CheckBox
        ObjectsPanel                    matlab.ui.container.Panel
        RowMinusButton_CellPosition     matlab.ui.control.Button
        RowPlusButton_CellPosition      matlab.ui.control.Button
        t_objects                       matlab.ui.control.Table
        add_obj_inFEM                   matlab.ui.control.CheckBox
        SolveFowardButton               matlab.ui.control.Button
        ax_sim_data                     matlab.ui.control.UIAxes
        SolveInverseTab                 matlab.ui.container.Tab
        loadandrecontaructmeasButton    matlab.ui.control.Button
        Makeinv_mdlButton               matlab.ui.control.Button
        SimGREITTable                   matlab.ui.control.Table
        SlicesforevalPanel              matlab.ui.container.Panel
        RowMinusButton_SimSlice         matlab.ui.control.Button
        RowPlusButton_SimSlice          matlab.ui.control.Button
        SimEvalSliceUITable             matlab.ui.control.Table
        inv_modelsolvingparameterPanel  matlab.ui.container.Panel
        imdljacobian_bkgndfuncDropDown  matlab.ui.control.DropDown
        imdljacobian_bkgndfuncDropDownLabel  matlab.ui.control.Label
        imdlhyperparameterparametersDropDown  matlab.ui.control.DropDown
        imdlhyperparameterparametersLabel  matlab.ui.control.Label
        imdljacobian_bkgndvalueEditField  matlab.ui.control.NumericEditField
        imdljacobian_bkgndvalueEditFieldLabel  matlab.ui.control.Label
        imdlhyperparametervalueEditField  matlab.ui.control.NumericEditField
        imdlhyperparametervalueEditFieldLabel  matlab.ui.control.Label
        imdlreconst_typeDropDown        matlab.ui.control.DropDown
        imdlreconst_typeDropDownLabel   matlab.ui.control.Label
        imdlhyperparameterfuncDropDown  matlab.ui.control.DropDown
        imdlhyperparameterfuncDropDownLabel  matlab.ui.control.Label
        imdlRtR_priorDropDown           matlab.ui.control.DropDown
        imdlRtR_priorDropDownLabel      matlab.ui.control.Label
        imdlsolveDropDown               matlab.ui.control.DropDown
        imdlsolveDropDownLabel          matlab.ui.control.Label
        SimulationdatasetnameEditField  matlab.ui.control.EditField
        SimulationdatasetnameEditFieldLabel  matlab.ui.control.Label
        LoadsimulationresultsButton     matlab.ui.control.Button
        SavesimulationresultsButton     matlab.ui.control.Button
        Transparency3DimdlSlider        matlab.ui.control.Slider
        Transparency3DimdlSliderLabel   matlab.ui.control.Label
        SliceZHeightSlider              matlab.ui.control.Slider
        SliceZHeightSliderLabel         matlab.ui.control.Label
        Makeinv_mdlSimulateButton       matlab.ui.control.Button
        recTab                          matlab.ui.container.Tab
        StateBtn_Reconst                matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        actualreconstructEditField      matlab.ui.control.NumericEditField
        actualreconstructEditFieldLabel  matlab.ui.control.Label
        RefFrameEditField               matlab.ui.control.NumericEditField
        RefFrameEditFieldLabel          matlab.ui.control.Label
        TotalFrameEditField             matlab.ui.control.NumericEditField
        TotalFrameEditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        Generate_user_entryTab          matlab.ui.container.Tab
        size_samplesnbEditField         matlab.ui.control.NumericEditField
        size_samplesnbEditFieldLabel    matlab.ui.control.Label
        size_single_datanbEditField     matlab.ui.control.NumericEditField
        size_single_datanbEditFieldLabel  matlab.ui.control.Label
        GenerateTrainingSamplesButton   matlab.ui.control.Button
        TypofrandominvsolverDropDown    matlab.ui.control.DropDown
        TypofrandominvsolverDropDownLabel  matlab.ui.control.Label
        typeofartifactsDropDown         matlab.ui.control.DropDown
        typeofartifactsDropDownLabel    matlab.ui.control.Label
        Savename_user_entrymatButton    matlab.ui.control.Button
        SNREditField                    matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        rangecellconductivityEditField  matlab.ui.control.EditField
        rangecellconductivityEditFieldLabel  matlab.ui.control.Label
        rangecellradiusEditField        matlab.ui.control.EditField
        rangecellradiusEditFieldLabel   matlab.ui.control.Label
        rangebufferconductivityEditField  matlab.ui.control.EditField
        rangebufferconductivityEditFieldLabel  matlab.ui.control.Label
        rangenumofcellEditField         matlab.ui.control.EditField
        rangenumofcellEditFieldLabel    matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        num_trainingdataEditField       matlab.ui.control.NumericEditField
        num_trainingdataEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        Path
        Figure
        CallingApp
        flag
        eit_env EIT_env
    end

    methods (Access = private)
        
        function app= Init_FwdModel_App(app)
            % Init the paths variable and
            %

            app.Init_Paths()
            app.Start_EIDORS_ToolBox()
            app.Init_globalVar_EIDORS()

            %% Init Data
            app.chTyp.Items =app.eit_env.setup.chamber.supported_forms();
            app.PatternGeneratorfuncDropDown.Items=app.eit_env.setup.pattern.get_generating_func();


            app.dataElecLayout.ColumnFormat={...
                app.eit_env.setup.elec_layout.supported_layouts(),...
                'numeric',...
                'numeric',...
                app.eit_env.setup.elec_layout.supported_elec_pos(),...
                app.eit_env.setup.elec_layout.supported_elec_forms(),...
                'numeric',...
                'numeric'};
            struct4gui= app.eit_env.setup.get_elec_layout_4_gui();
            app.dataElecLayout.Data=[struct2cell(struct4gui)]';
           
            app.t_objects.ColumnName={'Type', 'Position', 'Dimensions','Conductivity' };

            app.t_objects.Data = {'Cell' '[0,0,0]' '[0.1, 0, 0]' '[0.1]'};
            app.t_objects.ColumnFormat = {app.eit_env.sim.objects.allowed_type() 'char' 'char' 'char'};

            app.SimEvalSliceUITable.Data= {inf inf 0; inf inf 0; inf inf 0};
            

        end

        function app= Init_Paths(app)
            %% INIT Paths
            % set the used paths for Saving/Loading functions
            % app.Path.CurrentFolder is Define before the call of this function

            app.Path.Programms = [ app.Path.CurrentFolder filesep 'Sub_Programms'];
            app.Path.Setups = [app.Path.CurrentFolder filesep 'Setups'];
            % app.Path.FrequencySetups = [app.Path.CurrentFolder '\Setups\FrequencySetups'];
            % app.Path.InjectionPatterns= [app.Path.CurrentFolder '\Setups\InjectionPatterns'];
            % app.Path.BodyElectrodeSetups=[app.Path.CurrentFolder '\Setups\BodyElectrodeSetups'];
            app.Path.ChambersDesigns=[app.Path.CurrentFolder filesep 'Setups' filesep 'ChambersDesigns'];
            app.Path.fmdl=[app.Path.CurrentFolder filesep 'fmdl'];

            % test the paths if not exist they are created
            Test_Path(app.Path);
            % add the paths to matlab
            p=struct2cell(app.Path);
            for i=1:size(p,1)
                addpath(p{i});
            end

            if isempty(app.CallingApp)
                cd(app.Path.CurrentFolder); % go to current folder for this App
            else

            end
        end

        function app = Init_globalVar_EIDORS(app)
            global EIDORS
            Reset=0;
            if isempty(app.CallingApp)
                % if standalone Reset EIDORS to Default values contained in EIDORS_default.mat
                Reset= 1;
            else
                if isempty(EIDORS) % if by calling THIS APP, the var EIDORS do not exist Reset
                    Reset= 1;
                end
            end

            if Reset
                clear EIDORS
                clear global EIDORS
                global EIDORS
                tmp =load([app.Path.Setups filesep 'EIDORS_default.mat']);
                EIDORS=tmp.EIDORS;
                app.flag.redraw=0;
                EIDORS.sim.netgenAdditionalText="";

            end

        end

        function app= Start_EIDORS_ToolBox(app)

            if isempty(app.CallingApp)
                app.Path.EIDORS=Start_EIDORS();
            else
                disp('EIDORS already started from MainApp');
                app.Path.EIDORS= ['Started from MainApp: ' app.CallingApp.Path.EIDORS];
            end
        end
        

        function app=GetDataGUI(app)
            global EIDORS

            app.Path.EIDORSpath=app.PathEIDORSEditField.Value;
            
            disp('Update EIT_env')
%             % Chamber
%             app.eit_env.setup.chamber = EIT_chamber( ...
%                 app.chName.Value, ...
%                 [app.chBoxX.Value,app.chBoxY.Value, app.chBoxZ.Value], ...
%                 app.chFem.Value, ...
%                 app.chTyp.Value);
%             % Electrode layout
%             layout_data= cell2struct(app.dataElecLayout.Data, ...
%                 app.dataElecLayout.ColumnName, 2);
%             app.eit_env.setup.reset_elec_layout();
%             for i=1:length(layout_data)
%                 app.eit_env.setup.add_elec_layout( ...
%                     EIT_elec_layout(layout_data(i)) );           
%             end
            
%             app.eit_env.setup.pattern= EIT_pattern( ...
%                 app.AmplitudemAEditField.Value, ...
%                 app.Inj_pattern_typDropDown.Value, ...
%                 app.Inj_SpecialPatternEditField.Value, ...
%                 app.meas_pattern_typDropDown.Value, ...
%                 app.meas_SpecialPatternEditField.Value, ...
%                 app.option_mkstimpatternTextArea.Value, ...
%                 app.PatternGeneratorfuncDropDown.Value);
            
%             app.eit_env.fwd_model.set_solver( ...
%                 app.fwd_modelnameEditField.Value, ...
%                 app.fmdlsolveDropDown.Value, ...
%                 app.fmdljacobianDropDown.Value, ...
%                 app.fmdlsystem_matDropDown.Value, ...
%                 app.fmdlmiscperm_symDropDown.Value);              
            

%             EIDORS.chamber.name= app.chName.Value;
%             EIDORS.chamber.body.depth =app.chBoxY.Value;
%             EIDORS.chamber.body.diameter_length = app.chBoxX.Value;
%             EIDORS.chamber.body.FEM_refinement= app.chFem.Value;
%             EIDORS.chamber.body.height = app.chBoxZ.Value;
%             EIDORS.chamber.body.typ= app.chTyp.Value;
%             EIDORS.chamber.electrode=cell2struct(app.dataElecLayout.Data, app.dataElecLayout.ColumnName, 2);

%             app.flag.AddcellinFEMmodel=app.add_obj_inFEM.Value;
%             app.flag.holdexistingpattern=app.holdexistingpatternCheckBox.Value;
%             app.flag.displaymesh = app.pB_showmesh.Value;
%             app.flag.injPatteralldisplay=app.alldisplayCheckBox;
%             app.flag.injPatterAnimated=app.AnimatedCheckBox.Value;
%             app.flag.injPatterWithMeasPattern=app.withmeasPatternCheckBox.Value;
%             app.flag.manualMeshGenerate = app.manualmeshgenerateCheckBox.Value;
%             app.flag.showMeshQuality = app.showmeshqualityCheckBox.Value;
%             app.flag.fmdlGenerationFunction=
%             app.FmdlGenerationFunctionDropDown.Value; % not really used
%             app.flag.Object = app.ObjectDropDown.Value;

            EIDORS.fmdl.name = app.fwd_modelnameEditField.Value;%

            EIDORS.Pattern.amplitude = app.AmplitudemAEditField.Value;
            EIDORS.Pattern.Inj_Nr= str2num(app.InjectionNrDropDown.Value); % this one is not 
            EIDORS.Pattern.Inj_pattern_typ=app.Inj_pattern_typDropDown.Value;
            EIDORS.Pattern.Inj_specialpattern=app.Inj_SpecialPatternEditField.Value;
            EIDORS.Pattern.meas_pattern_typ=app.meas_pattern_typDropDown.Value;
            EIDORS.Pattern.meas_specialpattern=app.meas_SpecialPatternEditField.Value;
            EIDORS.Pattern.option_mkstimpattern= app.option_mkstimpatternTextArea.Value;
%             EIDORS.Pattern.PatternGeneratorfunc=app.PatternGeneratorfuncDropDown.Value;

            EIDORS.sim.name=app.SimulationdatasetnameEditField.Value;

%             EIDORS.sim.cell= cell2struct(app.t_objects.Data, app.t_objects.ColumnName,2);
            EIDORS.sim.bufferConduct=app.BufferconductEditField.Value;
%             EIDORS.sim.cellConduct=app.CellconductEditField.Value;
%             EIDORS.sim.layerConduct=app.LayerconductEditField.Value;

            EIDORS.sim.fmdl_jacobian=app.fmdljacobianDropDown.Value;%
            EIDORS.sim.fmdl_miscperm_sym=app.fmdlmiscperm_symDropDown.Value;%
            EIDORS.sim.fmdl_solve=app.fmdlsolveDropDown.Value;%
            EIDORS.sim.fmdl_system_mat=app.fmdlsystem_matDropDown.Value;%
%             
%             set_inv_solver( ...
%                 '', ...
%                 app.imdlsolveDropDown.Value, ...
%                 str2func(app.imdlRtR_priorDropDown.Value), ...
%                 '', ...
%                 app.imdlhyperparametervalueEditField.Value, ...
%                 app.imdlhyperparameterfuncDropDown.Value, ...
%                 app.imdlhyperparameterparametersDropDown.Value, ...
%                 app.imdljacobian_bkgndvalueEditField.Value, ...
%                 app.imdljacobian_bkgndfuncDropDown.Value, ...
%                 app.imdlreconst_typeDropDown.Value);
            
%             EIDORS.sim.imdl_hyperparameterfunc=app.imdlhyperparameterfuncDropDown.Value;
%             EIDORS.sim.imdl_hyperparameterparameters=app.imdlhyperparameterparametersDropDown.Value;
%             EIDORS.sim.imdl_hyperparametervalue=app.imdlhyperparametervalueEditField.Value;
%             EIDORS.sim.imdl_jacobian_bkgndfunc=app.imdljacobian_bkgndfuncDropDown.Value;
%             EIDORS.sim.imdl_jacobian_bkgndvalue=app.imdljacobian_bkgndvalueEditField.Value;
%             EIDORS.sim.imdl_reconst_type=app.imdlreconst_typeDropDown.Value;
%             EIDORS.sim.imdl_RtR_prior=str2func(app.imdlRtR_priorDropDown.Value);
%             EIDORS.sim.imdl_solve=app.imdlsolveDropDown.Value;

            EIDORS.sim.transparency=app.Transparency3DimdlSlider.Value/100;
            EIDORS.sim.levels= cell2mat(app.SimEvalSliceUITable.Data);

            EIDORS.rec.ref_frame= app.RefFrameEditField.Value;

        end
        
        function app= SetDataGUI(app)
            global EIDORS

            app.InjectionPatternTable.Data = [[1:size(EIDORS.Pattern.Injection,1)]' EIDORS.Pattern.Injection];
            for t=1:size(EIDORS.Pattern.Injection,1)
                Items(t) ={num2str(t)};
            end
            app.InjectionNrDropDown.Items= Items;

            app.MeasPatternTable.Data = EIDORS.Pattern.Disp_Meas;

            if ~isempty(app.CallingApp)
                UpdateDatefromsim(app.CallingApp); %% set data on calling App
            end
            if app.eit_env.setup.chamber.height()==0
                
            else
                app.SliceZHeightSlider.Limits=[-EIDORS.chamber.body.height/2,EIDORS.chamber.body.height/2];
            end
            

            app.PathEIDORSEditField.Value=app.Path.EIDORS;
            disp('Update GUI from EIT_env')
            % Chamber
            app.chName.Value    = app.eit_env.setup.chamber.name ;   
            app.chTyp.Value     = app.eit_env.setup.chamber.form ;
            app.chBoxX.Value    = app.eit_env.setup.chamber.length();
            app.chBoxZ.Value    = app.eit_env.setup.chamber.width();
            app.chBoxY.Value    = app.eit_env.setup.chamber.height();
            app.chFem.Value     = app.eit_env.setup.chamber.femRefinement;
            
%             % Electrode layout
%             struct4gui= app.eit_env.setup.get_elec_layout_4_gui();
%             app.dataElecLayout.Data=[struct2cell(struct4gui)]';


            app.fwd_modelnameEditField.Value = EIDORS.fmdl.name;
            app.SimulationdatasetnameEditField.Value=EIDORS.sim.name;


%             app.ObjectDropDown.Value = app.flag.Object;
%             app.chName.Value= EIDORS.chamber.name;
%             app.chBoxY.Value = EIDORS.chamber.body.depth ;
%             app.chBoxX.Value = EIDORS.chamber.body.diameter_length ;
%             app.chFem.Value = EIDORS.chamber.body.FEM_refinement;
%             app.chBoxZ.Value = EIDORS.chamber.body.height ;
%             app.chTyp.Value = EIDORS.chamber.body.typ;
%             app.t_objects.Data = [struct2cell(EIDORS.sim.cell)]';
%             app.dataElecLayout.Data = [struct2cell(EIDORS.chamber.electrode)]';
%             app.add_obj_inFEM.Value = app.flag.AddcellinFEMmodel;
%             app.pB_showmesh.Value = app.flag.displaymesh ;
%             app.holdexistingpatternCheckBox.Value = app.flag.holdexistingpattern;
%             app.alldisplayCheckBox = app.flag.injPatteralldisplay;
%             app.AnimatedCheckBox.Value = app.flag.injPatterAnimated;
%             app.withmeasPatternCheckBox.Value = app.flag.injPatterWithMeasPattern;
%             app.manualmeshgenerateCheckBox.Value = app.flag.manualMeshGenerate ;
%             app.showmeshqualityCheckBox.Value = app.flag.showMeshQuality ;
            app.fwd_modelnameEditField.Value = EIDORS.fmdl.name ;
%             app.FmdlGenerationFunctionDropDown.Value =
%             app.flag.fmdlGenerationFunction;% not used
            app.AmplitudemAEditField.Value = EIDORS.Pattern.amplitude ;
            app.InjectionNrDropDown.Value = string(EIDORS.Pattern.Inj_Nr);
            app.Inj_pattern_typDropDown.Value =  EIDORS.Pattern.Inj_pattern_typ;
            app.Inj_SpecialPatternEditField.Value =  EIDORS.Pattern.Inj_specialpattern;
            app.meas_pattern_typDropDown.Value = EIDORS.Pattern.meas_pattern_typ;
            app.meas_SpecialPatternEditField.Value = EIDORS.Pattern.meas_specialpattern;
            app.option_mkstimpatternTextArea.Value = EIDORS.Pattern.option_mkstimpattern;
%             app.PatternGeneratorfuncDropDown.Value = EIDORS.Pattern.PatternGeneratorfunc;
            app.BufferconductEditField.Value = EIDORS.sim.bufferConduct;
%             app.CellconductEditField.Value = EIDORS.sim.cellConduct;
            app.fmdljacobianDropDown.Value = EIDORS.sim.fmdl_jacobian;
            app.fmdlmiscperm_symDropDown.Value = EIDORS.sim.fmdl_miscperm_sym;
            app.fmdlsolveDropDown.Value = EIDORS.sim.fmdl_solve;
            app.fmdlsystem_matDropDown.Value = EIDORS.sim.fmdl_system_mat;
            app.imdlhyperparameterfuncDropDown.Value = EIDORS.sim.imdl_hyperparameterfunc;
            app.imdlhyperparameterparametersDropDown.Value = EIDORS.sim.imdl_hyperparameterparameters;
            app.imdlhyperparametervalueEditField.Value = EIDORS.sim.imdl_hyperparametervalue;
            app.imdljacobian_bkgndfuncDropDown.Value = EIDORS.sim.imdl_jacobian_bkgndfunc;
            app.imdljacobian_bkgndvalueEditField.Value = EIDORS.sim.imdl_jacobian_bkgndvalue;
            app.imdlreconst_typeDropDown.Value = EIDORS.sim.imdl_reconst_type;
            app.imdlRtR_priorDropDown.Value =func2str(EIDORS.sim.imdl_RtR_prior);
            app.imdlsolveDropDown.Value = EIDORS.sim.imdl_solve;
%             app.LayerconductEditField.Value = EIDORS.sim.layerConduct;
            app.SimulationdatasetnameEditField.Value = EIDORS.sim.name;
            app.Transparency3DimdlSlider.Value = EIDORS.sim.transparency*100;
            app.SimEvalSliceUITable.Data= mat2cell(EIDORS.sim.levels, ones(1,size(EIDORS.sim.levels,1)),ones(1,size(EIDORS.sim.levels,2)));
            app.SimGREITTable.Data=[struct2cell(EIDORS.sim.GREIT)]';
            app.SimGREITTable.ColumnName=fieldnames(EIDORS.sim.GREIT);

            %app.actualreconstructEditField.value= EIDORS.rec.actual_frame;
            %             app.TotalFrameEditField.Value= DataSet.Frame_count;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            %% Add the folder of THIS FILE and its subfolders
            p= mfilename('fullpath');
            indx = strfind(p, filesep);
            app.Path.CurrentFolder = p(1:indx(end)-1);
            addpath(replace(genpath(app.Path.CurrentFolder),[filesep 'old'],''));

            if exist('MainApp',"var")
                % Started from another App
                app.CallingApp = MainApp;
            end
            app.eit_env=EIT_env();
            
            app.Init_FwdModel_App();
            app.SetDataGUI();% update EIDORS Path on GUI
            % init some dropdown Menus

            PatternGeneratorfuncDropDownValueChanged(app)
            app.MakeFowardModelButton()
            app.PatterncreateButton()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Options button in main app
            if ~isempty(app.CallingApp)
                app.CallingApp.FowardModelconfigButton.Enable = 'on';
                UpdateDatefromsim(app.CallingApp)
            end
            delete(app)
        end

        % Button pushed function: SaveEIDORSButton
        function SaveEIDORSButtonPushed(app, event)
            app.GetDataGUI();
            LoadSave(app,'save','EIDORS','user')
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Button pushed function: LoadEIDORSButton
        function LoadEIDORSButtonPushed(app, event)
            app.GetDataGUI();
            LoadSave(app,'load','EIDORS','user')
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Button pushed function: MakeFowardModelButton
        function MakeFowardModelButtonPushed(app, event)
            
            % Chamber
            app.eit_env.setup.chamber = EIT_chamber( ...
                app.chName.Value, ...
                [app.chBoxX.Value,app.chBoxY.Value, app.chBoxZ.Value], ...
                app.chFem.Value, ...
                app.chTyp.Value);
            % Electrode layout
            layout_data= cell2struct(app.dataElecLayout.Data, ...
                app.dataElecLayout.ColumnName, 2);
            app.eit_env.setup.reset_elec_layout();
            for i=1:length(layout_data)
                app.eit_env.setup.add_elec_layout( ...
                    EIT_elec_layout(layout_data(i)) );           
            end
            
            % set the solver only when fwd model is created
            app.eit_env.fwd_model.set_solver( ...
                app.fwd_modelnameEditField.Value, ...
                app.fmdlsolveDropDown.Value, ...
                app.fmdljacobianDropDown.Value, ...
                app.fmdlsystem_matDropDown.Value, ...
                app.fmdlmiscperm_symDropDown.Value);
            
            % app.FmdlGenerationFunctionDropDown.Value %
            % if needed transfer this to create_fwd_model            
            [fmdl, success]=app.eit_env.create_fwd_model('');
            if success
%                 getCurrentFigure_with_figName('Forward Model')
%                 show_fem(fmdl);
                % Chamber
                app.chName.Value    = app.eit_env.setup.chamber.name ;   
                app.chTyp.Value     = app.eit_env.setup.chamber.form ;
                app.chBoxX.Value    = app.eit_env.setup.chamber.length();
                app.chBoxZ.Value    = app.eit_env.setup.chamber.width();
                app.chBoxY.Value    = app.eit_env.setup.chamber.height();
                app.chFem.Value     = app.eit_env.setup.chamber.femRefinement;
                
                % Electrode layout
                struct4gui= app.eit_env.setup.get_elec_layout_4_gui();
                t=[];
                for i=1:length(struct4gui)
                    t=[t; struct2cell(struct4gui(i))'];
                end
                app.dataElecLayout.Data=t;
                
                app.pB_showmeshValueChanged()
            end
        end

        % Button pushed function: Save_fwd_modelButton
        function Save_fwd_modelButtonPushed(app, event)
            app.GetDataGUI();
            LoadSave(app,'save','fmdl','user')
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Button pushed function: Load_fwd_modelButton
        function Load_fwd_modelButtonPushed(app, event)
            global EIDORS
            if LoadSave(app,'load','fmdl','user')
                app.SetDataGUI();% update
                app.flag.redraw=1;
                app.GetDataGUI();
                create_fmdl();
            end
        end

        % Button pushed function: pB_chSave
        function pB_chSavePushed(app, event)
            % Refactored
            app.GetDataGUI();
            app.eit_env.save( ...
                app.Path.ChambersDesigns, ...
                app.eit_env.setup.chamber.name);
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Button pushed function: pB_chLoad
        function pB_chLoadPushed(app, event)
            % Refactored
            app.GetDataGUI();
            [new_env, success]= app.eit_env.load( ...
                app.Path.ChambersDesigns, ...
                "");
            if success
                app.eit_env.setup.chamber= new_env.setup.chamber;
                app.SetDataGUI();
                figure(app.UIFigure);
            end
        end

        % Button pushed function: pB_plus_elec
        function pB_plus_elecPushed(app, event)
            app.dataElecLayout.Data=[app.dataElecLayout.Data; { 'Ring' 4 16 'Top' 'Circular' 0.5 0}];
        end

        % Button pushed function: pB_minus_elec
        function pB_minus_elecPushed(app, event)
            app.dataElecLayout.Data=app.dataElecLayout.Data(1:end-1,:);
        end

        % Button pushed function: PatterncreateButton
        function PatterncreateButtonPushed(app, event)

            app.eit_env.setup.pattern= EIT_pattern( ...
                app.AmplitudemAEditField.Value, ...
                app.Inj_pattern_typDropDown.Value, ...
                app.Inj_SpecialPatternEditField.Value, ...
                app.meas_pattern_typDropDown.Value, ...
                app.meas_SpecialPatternEditField.Value, ...
                app.option_mkstimpatternTextArea.Value, ...
                app.PatternGeneratorfuncDropDown.Value);
            app.eit_env.generate_pattern();
            
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_injections= [1:size(inj,1)];
            
            app.InjectionPatternTable.Data = [list_indx_injections' inj]; 
            app.InjectionNrDropDown.Items= string(num2cell(list_indx_injections));
            app.InjectionNrDropDown.Value= "1"; %% reset to injection nr 1
            InjectionNrDropDownValueChanged(app, event)%% for init EIDORS.Pattern.Disp_Meas
            
        end

        % Value changed function: InjectionNrDropDown
        function InjectionNrDropDownValueChanged(app, event)

            inj_indx= str2num(app.InjectionNrDropDown.Value);
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            app.MeasPatternTable.Data = meas(:,:,inj_indx);

        end

        % Button pushed function: DisplayInjectionPatternButton
        function DisplayInjectionPatternButtonPushed(app, event)

            fmdl=app.eit_env.fwd_model.fmdl;
            
            animated = app.AnimatedCheckBox.Value; % value : 0 or 1
            with_meas = app.withmeasPatternCheckBox.Value; % value : 0 or 1
            display_all=app.alldisplayCheckBox.Value; % value : 0 or 1
            
            if app.Own_DisplayScriptCheckBox.Value==1
                Own_DisplayScript_Pattern(app.eit_env.fwd_model, display_all,animated,with_meas)
            else
                displayStimOption={'n','y','na','ya'};
                option= displayStimOption{with_meas+2*animated+1};
                show_stim_meas_pattern(fmdl,option);
            end

        end

        % Button pushed function: 
        % DisplayfmdlwithElectrodesnumberButton
        function DisplayfmdlwithElectrodesnumberButtonPushed(app, event)

            getCurrentFigure_with_figName('Forward Model')
            fmdl=app.eit_env.fwd_model.fmdl;
            show_fem(fmdl, [0,1.012]);

        end

        % Button pushed function: SolveFowardButton
        function SolveFowardButtonPushed(app, event)
            global EIDORS
            app.GetDataGUI();
            
            %% set simulation Medium / Objects
            medium= app.BufferconductEditField.Value;
            objects= cell2struct(app.t_objects.Data, ...app.flag
                app.t_objects.ColumnName, 2);
            
            app.eit_env.set_sim(medium, objects)
            
            %% solve foward
            % app.add_obj_inFEM not implemented yet!
            app.eit_env.solve_fwd(app.add_obj_inFEM.Value)
            
            %%Plot 3D images
            figName= '3D Image of homogenious conductivity';
            h= getCurrentFigure_with_figName(figName);
            h= show_fem( app.eit_env.sim.img_h, [1,1.012,0]);
            if ~app.pB_showmesh.Value
                set(h,'EdgeColor','none');
            end
            figName= '3D Image of inhomogenious conductivity';
            h= getCurrentFigure_with_figName(figName);
            h= show_fem(app.eit_env.sim.img_ih,[1,0,0]);
            if ~app.pB_showmesh.Value
                set(h,'EdgeColor','none');
            end
%             show_cell(h,EIDORS.sim.iimg.calc_slices.levels,1)
            disp('Generate model with cell: Done!')
            
            
            %& Plot measurements
            axesHandle = app.ax_sim_data;%findobj('Tag', 'tagForYourAxes');
            ih = app.eit_env.sim.data_ih.meas;
            h = app.eit_env.sim.data_h.meas;
            plot(axesHandle,[ih, h, ih-h]);
            legend(axesHandle,{'ih', 'h', 'diff'}, "Location","northeast")
            
            if app.save_fwd_sol.Value==true
                name= [app.fwd_modelnameEditField.Value '.mat'];
                file=path_join(app.Path.CurrentFolder, name);
                X= ih;
                X_ih= ih;
                X_h= h;
                eit_env= app.eit_env;
                [file, path] =uiputfile({'*.mat','*.m'}, 'Enter a filename for saving the foward solution', file);
                if file==0
                    return;
                end
                file=path_join(path, file);
                save(file, "X", "X_ih", "X_h", "eit_env", '-mat')
            end
        end

        % Button pushed function: RowPlusButton_CellPosition
        function RowPlusButton_CellPositions_Pushed(app, event)
            app.t_objects.Data=[app.t_objects.Data; {'Cell' '[0,0,0]' '[0.1, 0, 0]' '[0.1]'};];
        end

        % Button pushed function: RowMinusButton_CellPosition
        function RowMinusButton_CellPositions_Pushed(app, event)
            app.t_objects.Data=app.t_objects.Data(1:end-1,:);
        end

        % Button pushed function: Makeinv_mdlSimulateButton
        function Makeinv_mdlSimulateButtonPushed(app, event)
            app.GetDataGUI();
            Simulate_fwd_model()
            app.SetDataGUI();
        end

        % Value changed function: SliceZHeightSlider
        function SliceZHeightSliderValueChanged(app, event)
            value = app.SliceZHeightSlider.Value;
            global EIDORS
            imag_layers =  [inf, inf, value];
            t= ['Slice at Z-height ' num2str(value, '%.3f') ' mm'];
            h= getCurrentFigure_with_figName(t);
            h= show_slices(EIDORS.sim.iimg, imag_layers);
            show_cell(h,imag_layers,0)
        end

        % Value changed function: Transparency3DimdlSlider
        function Transparency3DimdlSliderValueChanged(app, event)
            global EIDORS
            app.flag.redraw=1;
            app.GetDataGUI();
            Simulate_fwd_model()
        end

        % Button pushed function: SavesimulationresultsButton
        function SavesimulationresultsButtonPushed(app, event)
            app.GetDataGUI();;
            LoadSave(app,'save','simulation','user');
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Button pushed function: LoadsimulationresultsButton
        function LoadsimulationresultsButtonPushed(app, event)
            app.GetDataGUI();;
            LoadSave(app,'load','simulation','user');
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Value changed function: pB_showmesh
        function pB_showmeshValueChanged(app, event)
            figName= 'Mesh';
            h= getCurrentFigure_with_figName(figName);
            h= show_fem(app.eit_env.fwd_model.fmdl() ,[1,0,0]);
            if ~app.pB_showmesh.Value
                set(h,'EdgeColor','none');
            end
        end

        % Button pushed function: RowPlusButton_SimSlice
        function RowPlusButton_SimSlicePushed(app, event)
            app.SimEvalSliceUITable.Data=[app.SimEvalSliceUITable.Data; {Inf Inf 0}];
        end

        % Button pushed function: RowMinusButton_SimSlice
        function RowMinusButton_SimSlicePushed(app, event)
            app.SimEvalSliceUITable.Data=app.SimEvalSliceUITable.Data(1:end-1,:);
        end

        % Button pushed function: GetInputDataButton
        function GetInputDataButtonPushed(app, event)
            app.GetDataGUI();
        end

        % Button pushed function: SetInputDataButton
        function SetInputDataButtonPushed(app, event)
            app.SetDataGUI();
        end

        % Button pushed function: Makeinv_mdlButton
        function Makeinv_mdlButtonPushed(app, event)
%             global EIDORS
%             app.GetDataGUI();
%             create_imdl();
            app.eit_env.inv_model.set_inv_solver( ...
                '', ...
                app.imdlsolveDropDown.Value, ...
                app.imdlRtR_priorDropDown.Value, ...
                '', ...
                app.imdlhyperparametervalueEditField.Value, ...
                app.imdlhyperparameterfuncDropDown.Value, ...
                app.imdlhyperparameterparametersDropDown.Value, ...
                app.imdljacobian_bkgndvalueEditField.Value, ...
                app.imdljacobian_bkgndfuncDropDown.Value, ...
                app.imdlreconst_typeDropDown.Value);
            
            app.eit_env.inv_model.set_fwd_model( ...
                app.eit_env.fwd_model)
            
        end

        % Value changed function: PatternGeneratorfuncDropDown
        function PatternGeneratorfuncDropDownValueChanged(app, event)
            % adapt the choice of pattern typ for each function ...
            value = (app.PatternGeneratorfuncDropDown.Value);

            switch value
                case 'Ring patterning' % see in EIT_pattern for those values
                    pattern_types = {'{ad}';'{op}';'user defined'};
                    app.Inj_SpecialPatternEditField.Enable='on';
                    app.meas_SpecialPatternEditField.Enable='on';

                case 'Array patterning'
                    pattern_types = {'array_ad_simple';'array_ad_full';'array_ad_line';'array_op'};
                    app.Inj_SpecialPatternEditField.Enable='off';
                    app.meas_SpecialPatternEditField.Enable='off';
                case '3D patterning'
                    pattern_types = {'3d_ad_0';'3d_ad_1';'3d_ad_2';'3d_ad_3';'3d_adop_user';'3d_op_inoutplane';'3d_op'};
                    app.Inj_SpecialPatternEditField.Enable='on';
                    app.meas_SpecialPatternEditField.Enable='on';
                otherwise
            end

            app.Inj_pattern_typDropDown.Items= pattern_types;
            app.meas_pattern_typDropDown.Items= pattern_types;
            app.Inj_pattern_typDropDown.ItemsData= pattern_types;
            app.meas_pattern_typDropDown.ItemsData= pattern_types;
            app.GetDataGUI()
            app.SetDataGUI()
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
        end

        % Value changed function: StateBtn_Reconst
        function StateBtn_ReconstValueChanged(app, event)
            if app.StateBtn_Reconst.Value
                app.StateBtn_Reconst.Text="Stop reconstruction"
            else
                app.StateBtn_Reconst.Text="Start reconstruction"
            end

            while (app.StateBtn_Reconst.Value ==1)
                Reconstruction2
                % your iterative computation here
                i = i + 1;
            end
        end

        % Button pushed function: loadandrecontaructmeasButton
        function loadandrecontaructmeasButtonPushed(app, event)
            global EIDORS
            app.GetDataGUI();

            meas=load(uigetfile('.txt'))

            EIDORS.sim.data_h.meas=meas(:,1);
            EIDORS.sim.data_ih.meas=meas(:,2);
            Simulate_fwd_model()
            app.SetDataGUI();
        end

        % Button pushed function: Savename_user_entrymatButton
        function Savename_user_entrymatButtonPushed(app, event)
            global EIDORS

            u_entry= user_entry();
            u_entry.fmdl = EIDORS.fmdl;
            u_entry.chamber = EIDORS.chamber;


            u_entry.net_file_name =app.nameEditField.Value;                      
    
            u_entry.range_num_of_cells = str2num(app.rangenumofcellEditField.Value);            % = 10; % or e.g [5 10], one number indicates the maximum possible number of cells, two numbers means the range of possible number of cells, e.g. [5 10] from 5 to 10 cells
            u_entry.range_buffer_conductivity = str2num(app.rangebufferconductivityEditField.Value);     % = 5; % or e.g [5 10], one number indicates the maximum possible conductivity of the buffer, two numbers means the range of possible conductivity of the buffer
            u_entry.range_cell_conductivity = str2num(app.rangecellconductivityEditField.Value);       % = [5 10]; % or e.g [5 10], one number indicates the maximum possible conductivity of cells, two numbers means the range of possible conductivitie of cells
            u_entry.range_cell_radius = str2num(app.rangecellradiusEditField.Value);             % = 0.5; % or e.g [0.5 1.0], one number indicates the maximum possible radius of cells, two numbers means the range of possible radius of cells
            %cell_nucleus = [];                  % if 1 - depicts cell nucleus with bigger condutivity, if 0 - no nucleus is depicetd
            u_entry.SNR = app.SNREditField.Value;                           % noise ratio
            u_entry.inv_solver_name = app.TypofrandominvsolverDropDown.Value;               % 'GN' (for Gauss Newton one step) or 'TV' (for Total Variation/PDIPM)'
            u_entry.num_trainingData = app.num_trainingdataEditField.Value;
            
            u_entry.load_fmdl= 1;
            u_entry.type_of_artefacts=app.typeofartifactsDropDown.Value;
            u_entry.size_file_samples_max= app.size_samplesnbEditField.Value;
        u_entry.size_file_single_data_max= app.size_single_datanbEditField.Value;

            save([app.nameEditField.Value '_user_entry.mat'], "u_entry")


        end

        % Value changed function: chTyp
        function chTypValueChanged(app, event)
%             value = app.chTyp.Value;
%             if contains(value,'2D_Circ')
%                 app.chBoxZ.Value=0;
%             end
        end

        % Button pushed function: GenerateTrainingSamplesButton
        function GenerateTrainingSamplesButtonPushed(app, event)
            script_GenerateData
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FowardModelTab = uitab(app.TabGroup);
            app.FowardModelTab.Title = 'Foward Model';
            app.ForwardModelPanel = uipanel(app.FowardModelTab);
            app.ForwardModelPanel.Title = 'Forward Model';
            app.MakeFowardModelButton = uibutton(app.ForwardModelPanel, 'push');
            app.MakeFowardModelButton.ButtonPushedFcn = createCallbackFcn(app, @MakeFowardModelButtonPushed, true);
            app.MakeFowardModelButton.Text = 'Make Foward Model';
            app.showmeshqualityCheckBox = uicheckbox(app.ForwardModelPanel);
            app.showmeshqualityCheckBox.Enable = 'off';
            app.showmeshqualityCheckBox.Text = 'show mesh quality';
            app.Load_fwd_modelButton = uibutton(app.ForwardModelPanel, 'push');
            app.Load_fwd_modelButton.ButtonPushedFcn = createCallbackFcn(app, @Load_fwd_modelButtonPushed, true);
            app.Load_fwd_modelButton.Text = 'Load';
            app.Save_fwd_modelButton = uibutton(app.ForwardModelPanel, 'push');
            app.Save_fwd_modelButton.ButtonPushedFcn = createCallbackFcn(app, @Save_fwd_modelButtonPushed, true);
            app.Save_fwd_modelButton.Text = 'Save';
            app.fwd_modelsolvingparametersPanel = uipanel(app.ForwardModelPanel);
            app.fwd_modelsolvingparametersPanel.Title = 'fwd_model solving parameters';
            app.fmdlsolveDropDownLabel = uilabel(app.fwd_modelsolvingparametersPanel);
            app.fmdlsolveDropDownLabel.Text = 'fmdl.solve';
            app.fmdlsolveDropDown = uidropdown(app.fwd_modelsolvingparametersPanel);
            app.fmdlsolveDropDown.Items = {'eidors_default', 'fwd_solve_1st_order', 'aa_fwd_solve'};
            app.fmdlsolveDropDown.Value = 'eidors_default';
            app.fmdljacobianDropDownLabel = uilabel(app.fwd_modelsolvingparametersPanel);
            app.fmdljacobianDropDownLabel.Text = 'fmdl.jacobian';
            app.fmdljacobianDropDown = uidropdown(app.fwd_modelsolvingparametersPanel);
            app.fmdljacobianDropDown.Items = {'eidors_default', 'jacobian_adjoint', 'aa_calc_jacobian', ''};
            app.fmdljacobianDropDown.Value = 'eidors_default';
            app.fmdlsystem_matDropDownLabel = uilabel(app.fwd_modelsolvingparametersPanel);
            app.fmdlsystem_matDropDownLabel.Text = 'fmdl.system_mat';
            app.fmdlsystem_matDropDown = uidropdown(app.fwd_modelsolvingparametersPanel);
            app.fmdlsystem_matDropDown.Items = {'eidors_default', 'system_mat_1st_order', 'aa_calc_system_mat', ''};
            app.fmdlsystem_matDropDown.Value = 'eidors_default';
            app.fmdlmiscperm_symDropDownLabel = uilabel(app.fwd_modelsolvingparametersPanel);
            app.fmdlmiscperm_symDropDownLabel.Text = 'fmdl.misc.perm_sym';
            app.fmdlmiscperm_symDropDown = uidropdown(app.fwd_modelsolvingparametersPanel);
            app.fmdlmiscperm_symDropDown.Items = {'''{n}'''};
            app.fmdlmiscperm_symDropDown.Value = '''{n}''';
            app.pB_showmesh = uibutton(app.ForwardModelPanel, 'state');
            app.pB_showmesh.ValueChangedFcn = createCallbackFcn(app, @pB_showmeshValueChanged, true);
            app.pB_showmesh.Text = 'Show/hide mesh';
            app.pB_showmesh.Value = true;
            app.MeasurementChamberDesignPanel = uipanel(app.FowardModelTab);
            app.MeasurementChamberDesignPanel.Title = 'Measurement Chamber Design';
            app.BodyPanel = uipanel(app.MeasurementChamberDesignPanel);
            app.BodyPanel.Title = 'Body';
            app.TypDropDownLabel = uilabel(app.BodyPanel);
            app.TypDropDownLabel.Text = 'Typ';
            app.chTyp = uidropdown(app.BodyPanel);
            app.chTyp.Items = {'Cylinder', 'Cubic', '2D_Circ', ''};
            app.chTyp.ValueChangedFcn = createCallbackFcn(app, @chTypValueChanged, true);
            app.chTyp.Value = 'Cylinder';
            app.XEditFieldLabel = uilabel(app.BodyPanel);
            app.XEditFieldLabel.Text = 'X';
            app.chBoxX = uieditfield(app.BodyPanel, 'numeric');
            app.chBoxX.Value = 1;
            app.YEditFieldLabel = uilabel(app.BodyPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.chBoxY = uieditfield(app.BodyPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.BodyPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.chBoxZ = uieditfield(app.BodyPanel, 'numeric');
            app.chBoxZ.Value = 1;
            app.FEMRefinementEditFieldLabel = uilabel(app.BodyPanel);
            app.FEMRefinementEditFieldLabel.Text = 'FEM Refinement';
            app.chFem = uieditfield(app.BodyPanel, 'numeric');
            app.chFem.Value = 0.3;
            app.electrodes = uipanel(app.MeasurementChamberDesignPanel);
            app.electrodes.Title = 'Electrode';
            app.dataElecLayout = uitable(app.electrodes);
            app.dataElecLayout.ColumnName = {'Design'; 'Diameter'; 'Number'; 'Position'; 'Form'; 'Diameter_Width'; 'Height'};
            app.dataElecLayout.RowName = {};
            app.dataElecLayout.ColumnEditable = true;
            app.pB_plus_elec = uibutton(app.electrodes, 'push');
            app.pB_plus_elec.ButtonPushedFcn = createCallbackFcn(app, @pB_plus_elecPushed, true);
            app.pB_plus_elec.Text = '+';
            app.pB_minus_elec = uibutton(app.electrodes, 'push');
            app.pB_minus_elec.ButtonPushedFcn = createCallbackFcn(app, @pB_minus_elecPushed, true);
            app.pB_minus_elec.Text = '-';
            app.electrode_layout_info = uitextarea(app.electrodes);
            app.electrode_layout_info.Editable = 'off';
            app.electrode_layout_info.Value = {'For "array"-layout the number can be: '; '- an integer > square design '; '- a double to specify the number of rows and colums  e.g. Number = 5.02  >> nb rows (in x) = 5; nb columns (in y) = 2'};
            app.pB_chLoad = uibutton(app.MeasurementChamberDesignPanel, 'push');
            app.pB_chLoad.ButtonPushedFcn = createCallbackFcn(app, @pB_chLoadPushed, true);
            app.pB_chLoad.Text = 'Load';
            app.pB_chSave = uibutton(app.MeasurementChamberDesignPanel, 'push');
            app.pB_chSave.ButtonPushedFcn = createCallbackFcn(app, @pB_chSavePushed, true);
            app.pB_chSave.Text = 'Save';
            app.ChamberDesignnameEditFieldLabel = uilabel(app.MeasurementChamberDesignPanel);
            app.ChamberDesignnameEditFieldLabel.Text = 'ChamberDesign.name';
            app.chName = uieditfield(app.MeasurementChamberDesignPanel, 'text');
            app.chName.Value = 'ChamberDesign_defaultname';
            app.PatternTab = uitab(app.TabGroup);
            app.PatternTab.Title = 'Pattern';
            app.InjectionPatternPanel = uipanel(app.PatternTab);
            app.InjectionPatternPanel.Title = 'Injection Pattern';
            app.InjectionPatternTable = uitable(app.InjectionPatternPanel);
            app.InjectionPatternTable.ColumnName = {'nr'; 'Inj-'; 'Inj+'};
            app.InjectionPatternTable.RowName = {};
            app.DisplayInjectionPatternButton = uibutton(app.PatternTab, 'push');
            app.DisplayInjectionPatternButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayInjectionPatternButtonPushed, true);
            app.DisplayInjectionPatternButton.Text = 'Display Injection Pattern';
            app.MeasPatternPanel = uipanel(app.PatternTab);
            app.MeasPatternPanel.Title = 'Meas Pattern';
            app.MeasPatternTable = uitable(app.MeasPatternPanel);
            app.MeasPatternTable.ColumnName = {'Meas -'; 'Meas +'};
            app.MeasPatternTable.RowName = {};
            app.DisplayfmdlwithElectrodesnumberButton = uibutton(app.PatternTab, 'push');
            app.DisplayfmdlwithElectrodesnumberButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayfmdlwithElectrodesnumberButtonPushed, true);
            app.DisplayfmdlwithElectrodesnumberButton.Text = 'Display fmdl with Electrodes number';
            app.PatterncreateButton = uibutton(app.PatternTab, 'push');
            app.PatterncreateButton.ButtonPushedFcn = createCallbackFcn(app, @PatterncreateButtonPushed, true);
            app.PatterncreateButton.Text = 'Pattern create';
            app.holdexistingpatternCheckBox = uicheckbox(app.PatternTab);
            app.holdexistingpatternCheckBox.Enable = 'off';
            app.holdexistingpatternCheckBox.Text = 'hold existing pattern';
            app.AnimatedCheckBox = uicheckbox(app.PatternTab);
            app.AnimatedCheckBox.Text = 'Animated';
            app.withmeasPatternCheckBox = uicheckbox(app.PatternTab);
            app.withmeasPatternCheckBox.Text = 'with meas. Pattern';
            app.Own_DisplayScriptCheckBox = uicheckbox(app.PatternTab);
            app.Own_DisplayScriptCheckBox.Text = 'Own_DisplayScript';
            app.alldisplayCheckBox = uicheckbox(app.PatternTab);
            app.alldisplayCheckBox.Text = 'all display';
            app.InjectionNrDropDownLabel = uilabel(app.PatternTab);
            app.InjectionNrDropDownLabel.Text = 'Injection Nr.';
            app.InjectionNrDropDown = uidropdown(app.PatternTab);
            app.InjectionNrDropDown.Items = {'1', ''};
            app.InjectionNrDropDown.ValueChangedFcn = createCallbackFcn(app, @InjectionNrDropDownValueChanged, true);
            app.InjectionNrDropDown.Value = '1';
            app.PatternparameterPanel = uipanel(app.PatternTab);
            app.PatternparameterPanel.Title = 'Pattern parameter';
            app.TextArea = uitextarea(app.PatternparameterPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'First pattern is [x+1,y+1]'};
            app.TextArea_2 = uitextarea(app.PatternparameterPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'if contradictory options are specified, only the last applies'; '%       ''channel_voltages'''; '%       -> get only the voltages at electrodes'; '%      ''no_meas_current'' / ''meas_current'''; '%         -> do / don''t make mesurements on current carrying electrodes'; '%         -> to not make measurements on the electrodes next to the current carrying,'; '%            use ''no_meas_current_next1'''; '%         -> to not make measurements on the two electrodes next to the current carrying,'; '%            use ''no_meas_current_next2'''; '%         -> to not make measurements on the three electrodes next to the current carrying,'; '%            use ''no_meas_current_next3'''; '%      ''rotate_meas'' / ''no_rotate_meas'''; '%         -> do / don''t rotate measurements with stimulation pattern'; '%      ''do_redundant'' / ''no_redundant'''; '%         -> do / don''t make reciprocally redundant measures'; '%      ''balance_inj'' / ''no_balance_inj'''; '%         -> do / don''t draw current from all electrodes so total'; '%            injection is zero (useful for mono patterns)'; '%      ''balance_meas'' / ''no_balance_meas'''; '%         -> do / don''t subtrant measurement from all electrodes so total'; '%            average measurement is zero (useful for mono patterns)'};
            app.AmplitudemAEditFieldLabel = uilabel(app.PatternparameterPanel);
            app.AmplitudemAEditFieldLabel.Text = 'Amplitude [mA]';
            app.AmplitudemAEditField = uieditfield(app.PatternparameterPanel, 'numeric');
            app.AmplitudemAEditField.Value = 1;
            app.PatternGeneratorfuncDropDownLabel = uilabel(app.PatternparameterPanel);
            app.PatternGeneratorfuncDropDownLabel.Text = 'PatternGeneratorfunc';
            app.PatternGeneratorfuncDropDown = uidropdown(app.PatternparameterPanel);
            app.PatternGeneratorfuncDropDown.Items = {'mk_stim_pattern(EIDORS)', 'mk_stim_pattern_Array', 'mk_stim_3Dpattern'};
            app.PatternGeneratorfuncDropDown.ValueChangedFcn = createCallbackFcn(app, @PatternGeneratorfuncDropDownValueChanged, true);
            app.PatternGeneratorfuncDropDown.Value = 'mk_stim_pattern(EIDORS)';
            app.Inj_pattern_typDropDownLabel = uilabel(app.PatternparameterPanel);
            app.Inj_pattern_typDropDownLabel.Text = 'Inj_pattern_typ';
            app.Inj_pattern_typDropDown = uidropdown(app.PatternparameterPanel);
            app.Inj_pattern_typDropDown.Items = {'adjacent (EIDORS)', 'opposite (EIDORS)', 'user defined (EIDORS)', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op'};
            app.Inj_pattern_typDropDown.ItemsData = {'{ad}', '{op}', 'user defined', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.Inj_pattern_typDropDown.Value = '{ad}';
            app.Inj_SpecialPatternEditFieldLabel = uilabel(app.PatternparameterPanel);
            app.Inj_SpecialPatternEditFieldLabel.Text = 'Inj_SpecialPattern';
            app.Inj_SpecialPatternEditField = uieditfield(app.PatternparameterPanel, 'text');
            app.Inj_SpecialPatternEditField.Value = '[1 2]';
            app.meas_pattern_typDropDownLabel = uilabel(app.PatternparameterPanel);
            app.meas_pattern_typDropDownLabel.Text = 'meas_pattern_typ';
            app.meas_pattern_typDropDown = uidropdown(app.PatternparameterPanel);
            app.meas_pattern_typDropDown.Items = {'adjacent (EIDORS)', 'opposite (EIDORS)', 'user defined (EIDORS)', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.meas_pattern_typDropDown.ItemsData = {'{ad}', '{op}', 'user defined', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.meas_pattern_typDropDown.Value = '{ad}';
            app.meas_SpecialPatternEditFieldLabel = uilabel(app.PatternparameterPanel);
            app.meas_SpecialPatternEditFieldLabel.Text = 'meas_SpecialPattern';
            app.meas_SpecialPatternEditField = uieditfield(app.PatternparameterPanel, 'text');
            app.meas_SpecialPatternEditField.Value = '[1 2]';
            app.option_mkstimpatternTextAreaLabel = uilabel(app.PatternparameterPanel);
            app.option_mkstimpatternTextAreaLabel.Text = 'option_mkstimpattern';
            app.option_mkstimpatternTextArea = uitextarea(app.PatternparameterPanel);
            app.option_mkstimpatternTextArea.Value = {'meas_current'};
            app.SolvefowardTab = uitab(app.TabGroup);
            app.SolvefowardTab.Title = 'Solve foward';
            app.ax_sim_data = uiaxes(app.SolvefowardTab);
            title(app.ax_sim_data, 'Title')
            xlabel(app.ax_sim_data, 'X')
            ylabel(app.ax_sim_data, 'Y')
            zlabel(app.ax_sim_data, 'Z')
            app.SolveFowardButton = uibutton(app.SolvefowardTab, 'push');
            app.SolveFowardButton.ButtonPushedFcn = createCallbackFcn(app, @SolveFowardButtonPushed, true);
            app.SolveFowardButton.Text = 'Solve Foward';
            app.add_obj_inFEM = uicheckbox(app.SolvefowardTab);
            app.add_obj_inFEM.Enable = 'off';
            app.add_obj_inFEM.Text = 'Add object in FEM';
            app.ObjectsPanel = uipanel(app.SolvefowardTab);
            app.ObjectsPanel.Title = 'Objects';
            app.t_objects = uitable(app.ObjectsPanel);
            app.t_objects.ColumnName = {'PosX'; 'PosY'; 'PosZ'; 'Radius'};
            app.t_objects.RowName = {};
            app.t_objects.ColumnEditable = true;
            app.RowPlusButton_CellPosition = uibutton(app.ObjectsPanel, 'push');
            app.RowPlusButton_CellPosition.ButtonPushedFcn = createCallbackFcn(app, @RowPlusButton_CellPositions_Pushed, true);
            app.RowPlusButton_CellPosition.Text = '+';
            app.RowMinusButton_CellPosition = uibutton(app.ObjectsPanel, 'push');
            app.RowMinusButton_CellPosition.ButtonPushedFcn = createCallbackFcn(app, @RowMinusButton_CellPositions_Pushed, true);
            app.RowMinusButton_CellPosition.Text = '-';
            app.save_fwd_sol = uicheckbox(app.SolvefowardTab);
            app.save_fwd_sol.Text = 'Save foward solution';
            app.save_fwd_sol.Value = true;
            app.BufferconductEditFieldLabel = uilabel(app.SolvefowardTab);
            app.BufferconductEditFieldLabel.Text = 'Buffer conduct';
            app.BufferconductEditField = uieditfield(app.SolvefowardTab, 'numeric');
            app.BufferconductEditField.Value = 1;
            app.SolveInverseTab = uitab(app.TabGroup);
            app.SolveInverseTab.Title = 'Solve Inverse';
            app.Makeinv_mdlSimulateButton = uibutton(app.SolveInverseTab, 'push');
            app.Makeinv_mdlSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @Makeinv_mdlSimulateButtonPushed, true);
            app.Makeinv_mdlSimulateButton.Text = 'Make inv_mdl/Simulate';
            app.SliceZHeightSliderLabel = uilabel(app.SolveInverseTab);
            app.SliceZHeightSliderLabel.Text = 'Slice Z-Height';
            app.SliceZHeightSlider = uislider(app.SolveInverseTab);
            app.SliceZHeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliceZHeightSliderValueChanged, true);
            app.SliceZHeightSlider.Value = 50;
            app.Transparency3DimdlSliderLabel = uilabel(app.SolveInverseTab);
            app.Transparency3DimdlSliderLabel.Text = 'Transparency 3D imdl';
            app.Transparency3DimdlSlider = uislider(app.SolveInverseTab);
            app.Transparency3DimdlSlider.ValueChangedFcn = createCallbackFcn(app, @Transparency3DimdlSliderValueChanged, true);
            app.Transparency3DimdlSlider.Value = 100;
            app.SavesimulationresultsButton = uibutton(app.SolveInverseTab, 'push');
            app.SavesimulationresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesimulationresultsButtonPushed, true);
            app.SavesimulationresultsButton.Text = 'Save simulation results';
            app.LoadsimulationresultsButton = uibutton(app.SolveInverseTab, 'push');
            app.LoadsimulationresultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsimulationresultsButtonPushed, true);
            app.LoadsimulationresultsButton.Text = 'Load simulation results';
            app.SimulationdatasetnameEditFieldLabel = uilabel(app.SolveInverseTab);
            app.SimulationdatasetnameEditFieldLabel.Text = 'Simulation data set name';
            app.SimulationdatasetnameEditField = uieditfield(app.SolveInverseTab, 'text');
            app.SimulationdatasetnameEditField.Value = 'Simulation default';
            app.inv_modelsolvingparameterPanel = uipanel(app.SolveInverseTab);
            app.inv_modelsolvingparameterPanel.Title = 'inv_model solving parameter';
            app.imdlsolveDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlsolveDropDownLabel.Text = 'imdl.solve';
            app.imdlsolveDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlsolveDropDown.Items = {'eidors_default', 'inv_solve_TSVD', 'inv_solve_TV_irls', 'inv_solve_TV_pdipm', 'inv_solve_abs_GN_constrain', 'inv_solve_abs_GN_prior', 'inv_solve_abs_annealingMetropolis_params', 'inv_solve_abs_annealingSimplex_params', 'inv_solve_abs_pdipm', 'inv_solve_backproj', 'inv_solve_cg', 'inv_solve_conj_grad', 'inv_solve_core', 'inv_solve_diff_GN_one_step', 'inv_solve_diff_kalman', 'inv_solve_diff_pdipm', 'inv_solve_gn', 'inv_solve_mcmc', 'inv_solve_time_prior', 'inv_solve_trunc_iterative '};
            app.imdlsolveDropDown.Value = 'eidors_default';
            app.imdlRtR_priorDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlRtR_priorDropDownLabel.Text = 'imdl.RtR_prior';
            app.imdlRtR_priorDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlRtR_priorDropDown.Items = {'eidors_default', 'prior_TV', 'prior_covar', 'prior_exponential_covar', 'prior_gaussian_HPF', 'prior_gaussian_likelihood', 'prior_laplace', 'prior_laplace_old', 'prior_movement', 'prior_noser', 'prior_tikhonov', 'prior_time_smooth', ''};
            app.imdlRtR_priorDropDown.Value = 'eidors_default';
            app.imdlhyperparameterfuncDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparameterfuncDropDownLabel.Text = 'imdl.hyperparameter.func';
            app.imdlhyperparameterfuncDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparameterfuncDropDown.Items = {'NOT USED', 'aa_calc_noise_figure', ''};
            app.imdlhyperparameterfuncDropDown.Value = 'NOT USED';
            app.imdlreconst_typeDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlreconst_typeDropDownLabel.Text = 'imdl.reconst_type';
            app.imdlreconst_typeDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlreconst_typeDropDown.Items = {'difference', 'static'};
            app.imdlreconst_typeDropDown.Value = 'difference';
            app.imdlhyperparametervalueEditFieldLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparametervalueEditFieldLabel.Text = 'imdl.hyperparameter.value';
            app.imdlhyperparametervalueEditField = uieditfield(app.inv_modelsolvingparameterPanel, 'numeric');
            app.imdlhyperparametervalueEditField.Value = 0.01;
            app.imdljacobian_bkgndvalueEditFieldLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdljacobian_bkgndvalueEditFieldLabel.Text = 'imdl.jacobian_bkgnd.value';
            app.imdljacobian_bkgndvalueEditField = uieditfield(app.inv_modelsolvingparameterPanel, 'numeric');
            app.imdljacobian_bkgndvalueEditField.Value = 1;
            app.imdlhyperparameterparametersLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparameterparametersLabel.Text = 'imdl.hyperparameter.parameters';
            app.imdlhyperparameterparametersDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdlhyperparameterparametersDropDown.Items = {'NOT USED', 'noise_figure', ''};
            app.imdlhyperparameterparametersDropDown.Value = 'NOT USED';
            app.imdljacobian_bkgndfuncDropDownLabel = uilabel(app.inv_modelsolvingparameterPanel);
            app.imdljacobian_bkgndfuncDropDownLabel.Text = 'imdl.jacobian_bkgnd.func';
            app.imdljacobian_bkgndfuncDropDown = uidropdown(app.inv_modelsolvingparameterPanel);
            app.imdljacobian_bkgndfuncDropDown.Items = {'NOT USED'};
            app.imdljacobian_bkgndfuncDropDown.Value = 'NOT USED';
            app.SlicesforevalPanel = uipanel(app.SolveInverseTab);
            app.SlicesforevalPanel.Title = 'Slices for eval';
            app.SimEvalSliceUITable = uitable(app.SlicesforevalPanel);
            app.SimEvalSliceUITable.ColumnName = {'x='; 'y='; 'z='};
            app.SimEvalSliceUITable.RowName = {};
            app.SimEvalSliceUITable.ColumnEditable = true;
            app.RowPlusButton_SimSlice = uibutton(app.SlicesforevalPanel, 'push');
            app.RowPlusButton_SimSlice.ButtonPushedFcn = createCallbackFcn(app, @RowPlusButton_SimSlicePushed, true);
            app.RowPlusButton_SimSlice.Text = '+';
            app.RowMinusButton_SimSlice = uibutton(app.SlicesforevalPanel, 'push');
            app.RowMinusButton_SimSlice.ButtonPushedFcn = createCallbackFcn(app, @RowMinusButton_SimSlicePushed, true);
            app.RowMinusButton_SimSlice.Text = '-';
            app.SimGREITTable = uitable(app.SolveInverseTab);
            app.SimGREITTable.ColumnName = {'AmpR'; 'PosError'; 'Res'; 'ShapeDef'; 'Ringing'};
            app.SimGREITTable.RowName = {};
            app.SimGREITTable.ColumnEditable = true;
            app.Makeinv_mdlButton = uibutton(app.SolveInverseTab, 'push');
            app.Makeinv_mdlButton.ButtonPushedFcn = createCallbackFcn(app, @Makeinv_mdlButtonPushed, true);
            app.Makeinv_mdlButton.Text = 'Make inv_mdl';
            app.loadandrecontaructmeasButton = uibutton(app.SolveInverseTab, 'push');
            app.loadandrecontaructmeasButton.ButtonPushedFcn = createCallbackFcn(app, @loadandrecontaructmeasButtonPushed, true);
            app.loadandrecontaructmeasButton.Text = 'load and recontaructmeas';
            app.recTab = uitab(app.TabGroup);
            app.recTab.Title = 'rec';
            app.UIAxes = uiaxes(app.recTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TotalFrameEditFieldLabel = uilabel(app.recTab);
            app.TotalFrameEditFieldLabel.Text = 'Total Frame';
            app.TotalFrameEditField = uieditfield(app.recTab, 'numeric');
            app.RefFrameEditFieldLabel = uilabel(app.recTab);
            app.RefFrameEditFieldLabel.Text = 'Ref Frame';
            app.RefFrameEditField = uieditfield(app.recTab, 'numeric');
            app.actualreconstructEditFieldLabel = uilabel(app.recTab);
            app.actualreconstructEditFieldLabel.Text = 'actual reconstruct';
            app.actualreconstructEditField = uieditfield(app.recTab, 'numeric');
            app.StartButton = uibutton(app.recTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.recTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StateBtn_Reconst = uibutton(app.recTab, 'state');
            app.StateBtn_Reconst.ValueChangedFcn = createCallbackFcn(app, @StateBtn_ReconstValueChanged, true);
            app.StateBtn_Reconst.Text = {'Start reconstruction'; ''};
            app.Generate_user_entryTab = uitab(app.TabGroup);
            app.Generate_user_entryTab.Tooltip = {''};
            app.Generate_user_entryTab.Title = 'Generate_user_entry';
            app.num_trainingdataEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.num_trainingdataEditFieldLabel.Text = 'num_trainingdata';
            app.num_trainingdataEditField = uieditfield(app.Generate_user_entryTab, 'numeric');
            app.num_trainingdataEditField.Tooltip = {'Enter numbers of samples to generate'};
            app.num_trainingdataEditField.Value = 1000;
            app.nameEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.nameEditField.Value = 'cytoplasm';
            app.rangenumofcellEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.rangenumofcellEditFieldLabel.Text = 'range num of cell ';
            app.rangenumofcellEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.rangenumofcellEditField.Tooltip = {'Enter a range of  number of cell; like [2,3] >  random generation between 2 and 3 cells'};
            app.rangenumofcellEditField.Value = '[2 5]';
            app.rangebufferconductivityEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.rangebufferconductivityEditFieldLabel.Text = 'range buffer conductivity';
            app.rangebufferconductivityEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.rangebufferconductivityEditField.Tooltip = {'Enter a range of  number of cell; like [1.5,2] >  random generation between 2 and 3 '};
            app.rangebufferconductivityEditField.Value = '[0.5 0.5]';
            app.rangecellradiusEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.rangecellradiusEditFieldLabel.Text = 'range cell radius';
            app.rangecellradiusEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.rangecellradiusEditField.Tooltip = {'Enter a range of  radius of cell; like [0.5 0.5] '};
            app.rangecellradiusEditField.Value = '[0.4 0.5]';
            app.rangecellconductivityEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.rangecellconductivityEditFieldLabel.Text = 'range cell conductivity ';
            app.rangecellconductivityEditField = uieditfield(app.Generate_user_entryTab, 'text');
            app.rangecellconductivityEditField.Tooltip = {'Enter a range of  number of cell; like [2,3] >  random generation between 2 and 3 cells'};
            app.rangecellconductivityEditField.Value = '[1 1.5 1; 2 2.5 0.6]';
            app.SNREditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.Generate_user_entryTab, 'numeric');
            app.SNREditField.Value = 20;
            app.Savename_user_entrymatButton = uibutton(app.Generate_user_entryTab, 'push');
            app.Savename_user_entrymatButton.ButtonPushedFcn = createCallbackFcn(app, @Savename_user_entrymatButtonPushed, true);
            app.Savename_user_entrymatButton.Text = 'Save name_user_entry.mat ';
            app.typeofartifactsDropDownLabel = uilabel(app.Generate_user_entryTab);
            app.typeofartifactsDropDownLabel.Text = 'type of artifacts';
            app.typeofartifactsDropDown = uidropdown(app.Generate_user_entryTab);
            app.typeofartifactsDropDown.Items = {'Cells', 'Antigens', 'Random'};
            app.typeofartifactsDropDown.Value = 'Cells';
            app.TypofrandominvsolverDropDownLabel = uilabel(app.Generate_user_entryTab);
            app.TypofrandominvsolverDropDownLabel.Enable = 'off';
            app.TypofrandominvsolverDropDownLabel.Text = 'Typ of random invsolver';
            app.TypofrandominvsolverDropDown = uidropdown(app.Generate_user_entryTab);
            app.TypofrandominvsolverDropDown.Items = {'GN', 'TV'};
            app.TypofrandominvsolverDropDown.Enable = 'off';
            app.TypofrandominvsolverDropDown.Value = 'GN';
            app.GenerateTrainingSamplesButton = uibutton(app.Generate_user_entryTab, 'push');
            app.GenerateTrainingSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrainingSamplesButtonPushed, true);
            app.GenerateTrainingSamplesButton.Text = 'Generate Training Samples';
            app.size_single_datanbEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.size_single_datanbEditFieldLabel.Text = 'size_single_data(nb)';
            app.size_single_datanbEditField = uieditfield(app.Generate_user_entryTab, 'numeric');
            app.size_single_datanbEditField.Value = 250;
            app.size_samplesnbEditFieldLabel = uilabel(app.Generate_user_entryTab);
            app.size_samplesnbEditFieldLabel.Text = 'size_samples (nb)';
            app.size_samplesnbEditField = uieditfield(app.Generate_user_entryTab, 'numeric');
            app.size_samplesnbEditField.Value = 1000;
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'FwdModel_App by Metz';
            app.fwd_modelnameEditFieldLabel = uilabel(app.UIFigure);
            app.fwd_modelnameEditFieldLabel.Text = 'fwd_model.name';
            app.fwd_modelnameEditField = uieditfield(app.UIFigure, 'text');
            app.fwd_modelnameEditField.Value = 'fwd_model_defaultname';
            app.SaveEIDORSButton = uibutton(app.UIFigure, 'push');
            app.SaveEIDORSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEIDORSButtonPushed, true);
            app.SaveEIDORSButton.Text = 'Save EIDORS';
            app.PathEIDORSEditFieldLabel = uilabel(app.UIFigure);
            app.PathEIDORSEditFieldLabel.Text = 'Path EIDORS';
            app.PathEIDORSEditField = uieditfield(app.UIFigure, 'text');
            app.GetInputDataButton = uibutton(app.UIFigure, 'push');
            app.GetInputDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetInputDataButtonPushed, true);
            app.GetInputDataButton.Text = 'GetInputData';
            app.SetInputDataButton = uibutton(app.UIFigure, 'push');
            app.SetInputDataButton.ButtonPushedFcn = createCallbackFcn(app, @SetInputDataButtonPushed, true);
            app.SetInputDataButton.Text = 'SetInputData';
            app.LoadEIDORSButton = uibutton(app.UIFigure, 'push');
            app.LoadEIDORSButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEIDORSButtonPushed, true);
            app.LoadEIDORSButton.Text = 'Load EIDORS';
            app.CellconductEditFieldLabel = uilabel(app.UIFigure);
            app.CellconductEditFieldLabel.Text = 'Cell conduct';
            app.CellconductEditField = uieditfield(app.UIFigure, 'numeric');
            app.CellconductEditField.Value = 0.001;
            app.ObjectDropDownLabel = uilabel(app.UIFigure);
            app.ObjectDropDownLabel.Text = 'Object';
            app.ObjectDropDown = uidropdown(app.UIFigure);
            app.ObjectDropDown.Items = {'Cell', 'Cylinder', 'Sphere', ''};
            app.ObjectDropDown.Value = 'Cell';
            app.LayerconductEditFieldLabel = uilabel(app.UIFigure);
            app.LayerconductEditFieldLabel.Text = 'Layer conduct';
            app.LayerconductEditField = uieditfield(app.UIFigure, 'numeric');
            app.LayerconductEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FwdModel_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end