classdef FwdModel_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EITSoftware                     matlab.ui.Figure
        EITSoftwareGrid                 matlab.ui.container.GridLayout
        TabMain                         matlab.ui.container.TabGroup
        GlobalSettingsTab               matlab.ui.container.Tab
        GlobalSettingsTabGrid           matlab.ui.container.GridLayout
        Title                           matlab.ui.control.TextArea
        EITEnvControlPanel              matlab.ui.container.Panel
        EITEnvControlPanelGrid          matlab.ui.container.GridLayout
        lab_eit_env_name                matlab.ui.control.Label
        txt_eit_env_name                matlab.ui.control.EditField
        pB_eit_env_Save                 matlab.ui.control.Button
        pB_eit_env_Load                 matlab.ui.control.Button
        chB_eit_env_export2py           matlab.ui.control.CheckBox
        GeneralSettingsPanel            matlab.ui.container.Panel
        GeneralSettingsPanelGrid        matlab.ui.container.GridLayout
        PathEIDORSEditFieldLabel_2      matlab.ui.control.Label
        txt_eidors_path                 matlab.ui.control.EditField
        UnitsLabel                      matlab.ui.control.Label
        dD_unit_select                  matlab.ui.control.DropDown
        ForwardModelTab                 matlab.ui.container.Tab
        ForwardModelTabGrid             matlab.ui.container.GridLayout
        ForwardModelTabGroup            matlab.ui.container.TabGroup
        ChamberTab                      matlab.ui.container.Tab
        ChamberTabGrid                  matlab.ui.container.GridLayout
        NameofthechamberLabel           matlab.ui.control.Label
        txt_chamber_name                matlab.ui.control.EditField
        BodyPanel                       matlab.ui.container.Panel
        BodyPanelGrid                   matlab.ui.container.GridLayout
        XEditFieldLabel                 matlab.ui.control.Label
        dB_chamber_boxX                 matlab.ui.control.NumericEditField
        lab_chamber_typ                 matlab.ui.control.Label
        dD_chamber_typ                  matlab.ui.control.DropDown
        lab_chamber_boxY                matlab.ui.control.Label
        dB_chamber_boxY                 matlab.ui.control.NumericEditField
        lab_chamber_boxZ                matlab.ui.control.Label
        dB_chamber_boxZ                 matlab.ui.control.NumericEditField
        lab_chamber_refinement          matlab.ui.control.Label
        dB_chamber_refinement           matlab.ui.control.NumericEditField
        pB_chamber_Load                 matlab.ui.control.Button
        pB_chamber_Save                 matlab.ui.control.Button
        ElecLayoutPanel                 matlab.ui.container.Panel
        ElecLayoutPanelGrid             matlab.ui.container.GridLayout
        table_chamber_elec_layout       matlab.ui.control.Table
        pB_chamber_elec_layout_Add      matlab.ui.control.Button
        pB_chamber_elec_layout_Del      matlab.ui.control.Button
        pB_chamber_elec_layout_Readme   matlab.ui.control.Button
        PatternTab                      matlab.ui.container.Tab
        PatternTabGrid                  matlab.ui.container.GridLayout
        InjectionNrDropDownLabel        matlab.ui.control.Label
        dD_pattern_actual_inj_nb        matlab.ui.control.DropDown
        InjectionPatternPanel           matlab.ui.container.Panel
        InjectionPatternPanelGrid       matlab.ui.container.GridLayout
        table_pattern_inj               matlab.ui.control.Table
        MeasPatternPanel                matlab.ui.container.Panel
        MeasPatternPanelGrid            matlab.ui.container.GridLayout
        table_pattern_meas              matlab.ui.control.Table
        PatterncontrolPanel             matlab.ui.container.Panel
        PatterncontrolPanelGrid         matlab.ui.container.GridLayout
        OptionsLabel                    matlab.ui.control.Label
        txt_pattern_option              matlab.ui.control.TextArea
        MeasuserpatternLabel            matlab.ui.control.Label
        txt_pattern_meas_special        matlab.ui.control.EditField
        MeasurementpatternLabel         matlab.ui.control.Label
        dD_pattern_meas_typ             matlab.ui.control.DropDown
        InjuserpatternLabel             matlab.ui.control.Label
        txt_pattern_inj_special         matlab.ui.control.EditField
        InjectionpatternLabel           matlab.ui.control.Label
        dD_pattern_inj_type             matlab.ui.control.DropDown
        PatterningfunctionLabel         matlab.ui.control.Label
        dD_pattern_func                 matlab.ui.control.DropDown
        AmplitudeALabel                 matlab.ui.control.Label
        dB_pattern_amplitude            matlab.ui.control.NumericEditField
        lab_pattern_option_info         matlab.ui.control.TextArea
        FwdModelGenerationPanel         matlab.ui.container.Panel
        FwdModelGenerationPanelGrid     matlab.ui.container.GridLayout
        pB_fmdl_Build                   matlab.ui.control.Button
        txt_fmdl_pattern_select_inj_to_keep  matlab.ui.control.EditField
        InjectionrangetokeepLabel       matlab.ui.control.Label
        pB_fmdl_trunc_injection         matlab.ui.control.Button
        PatternDisplayControlPanel      matlab.ui.container.Panel
        PatternDisplayControlPanelGrid  matlab.ui.container.GridLayout
        sw_pattern_func_display         matlab.ui.control.Switch
        cB_pattern_all_display          matlab.ui.control.CheckBox
        cB_pattern_animated             matlab.ui.control.CheckBox
        cB_pattern_with_meas            matlab.ui.control.CheckBox
        pB_pattern_Display              matlab.ui.control.Button
        cB_fmdl_pattern_hold            matlab.ui.control.CheckBox
        pB_fmdl_pattern_Build           matlab.ui.control.Button
        NameoftheForwardmodelLabel      matlab.ui.control.Label
        txt_fmdl_name                   matlab.ui.control.EditField
        FEMDisplayControlPanel          matlab.ui.container.Panel
        FEMDisplayControlPanelGrid      matlab.ui.container.GridLayout
        ElectrodesLabel                 matlab.ui.control.Label
        sw_fem_show_elec_nb             matlab.ui.control.Switch
        MeshLabel                       matlab.ui.control.Label
        sw_fem_show_mesh                matlab.ui.control.Switch
        cB_fem_show_mesh_quality        matlab.ui.control.CheckBox
        pB_fmdl_Save                    matlab.ui.control.Button
        pB_fmdl_Load                    matlab.ui.control.Button
        pB_fmdl_fem_Build               matlab.ui.control.Button
        SolveForwardTab                 matlab.ui.container.Tab
        SolveForwardTabGrid             matlab.ui.container.GridLayout
        FMDLSolverControlPanel          matlab.ui.container.Panel
        FMDLSolverControlPanelGrid      matlab.ui.container.GridLayout
        dD_fmdl_miscperm_sym            matlab.ui.control.DropDown
        Miscperm_symLabel               matlab.ui.control.Label
        dD_fmdl_systmat                 matlab.ui.control.DropDown
        System_matLabel                 matlab.ui.control.Label
        dD_fmdl_jac                     matlab.ui.control.DropDown
        JacobianLabel                   matlab.ui.control.Label
        dD_fmdl_solve                   matlab.ui.control.DropDown
        SolveLabel                      matlab.ui.control.Label
        ConductivityChamberControlPanel  matlab.ui.container.Panel
        ConductivityChamberControlPanelGrid  matlab.ui.container.GridLayout
        MediumconductivityEditFieldLabel  matlab.ui.control.Label
        dB_sim_medium_conduct           matlab.ui.control.NumericEditField
        ObjectsPanel                    matlab.ui.container.Panel
        ObjectsPanelGrid                matlab.ui.container.GridLayout
        pB_sim_objects_Readme           matlab.ui.control.Button
        table_sim_objects               matlab.ui.control.Table
        pB_sim_objects_Add              matlab.ui.control.Button
        pB_sim_objects_Del              matlab.ui.control.Button
        Panel_sim_controls              matlab.ui.container.Panel
        Panel_sim_controls_grid         matlab.ui.container.GridLayout
        pB_sim_Save                     matlab.ui.control.Button
        pB_sim_Load                     matlab.ui.control.Button
        pB_sim_Display                  matlab.ui.control.Button
        pB_sim_Solve                    matlab.ui.control.Button
        chB_sim_add_obj_in_fem          matlab.ui.control.CheckBox
        SolveInverseTab                 matlab.ui.container.Tab
        SolveInverseTabGrid             matlab.ui.container.GridLayout
        IMDLControlPanel                matlab.ui.container.Panel
        IMDLControlPanelGrid            matlab.ui.container.GridLayout
        NameEditFieldLabel              matlab.ui.control.Label
        txt_imdl_name                   matlab.ui.control.EditField
        JacobianbkgndfuncLabel          matlab.ui.control.Label
        dD_imdl_jac_bkgnd_func          matlab.ui.control.DropDown
        HyperparameterparametersLabel   matlab.ui.control.Label
        dD_imdl_hyperparam_params       matlab.ui.control.DropDown
        JacobianbkgndvalueLabel         matlab.ui.control.Label
        dD_imdl_jac_bkgnd_val           matlab.ui.control.NumericEditField
        HyperparametervalueLabel        matlab.ui.control.Label
        dD_imdl_hyperparam_val          matlab.ui.control.NumericEditField
        ReconstructiontypeLabel         matlab.ui.control.Label
        dD_imdl_reconst_type            matlab.ui.control.DropDown
        HyperparameterfuncLabel         matlab.ui.control.Label
        dD_imdl_hyperparam_func         matlab.ui.control.DropDown
        RtRpriorLabel                   matlab.ui.control.Label
        dD_imdl_RtR_prior               matlab.ui.control.DropDown
        SolveLabel_2                    matlab.ui.control.Label
        dD_imdl_solve                   matlab.ui.control.DropDown
        table_rec_GREIT_results         matlab.ui.control.Table
        ReconstructionControlPanel      matlab.ui.container.Panel
        ReconstructionControlPanelGrid  matlab.ui.container.GridLayout
        pB_rec_Save                     matlab.ui.control.Button
        pB_rec_Load                     matlab.ui.control.Button
        pB_rec_Display                  matlab.ui.control.Button
        TransparencyimgLabel            matlab.ui.control.Label
        slider_rec_transparency         matlab.ui.control.Slider
        pB_rec_Solve                    matlab.ui.control.Button
        cB_rec_load_meas                matlab.ui.control.CheckBox
        RecSlicesPanel                  matlab.ui.container.Panel
        RecSlicesPanelGrid              matlab.ui.container.GridLayout
        SliceZHeightSliderLabel         matlab.ui.control.Label
        slider_rec_set_slice_z          matlab.ui.control.Slider
        tab_rec_slices                  matlab.ui.control.Table
        pB_rec_slice_row_add            matlab.ui.control.Button
        pB_rec_slice_row_del            matlab.ui.control.Button
        AIDatasetTab                    matlab.ui.container.Tab
        AIDatasetTabGrid                matlab.ui.container.GridLayout
        DatasetInputPanel               matlab.ui.container.Panel
        DatasetInputPanelGrid           matlab.ui.container.GridLayout
        pB_dataset_user_entry_Save      matlab.ui.control.Button
        table_dataset_user_entry        matlab.ui.control.Table
        pB_dataset_user_entry_Readme    matlab.ui.control.Button
        GenerationDatasetPanel          matlab.ui.container.Panel
        GenerationDatasetPanelGrid      matlab.ui.container.GridLayout
        chB_dataset_samples_load_idx    matlab.ui.control.CheckBox
        chB_dataset_samples_rec_extract_elem_data  matlab.ui.control.CheckBox
        pB_dataset_samples_rec          matlab.ui.control.Button
        SamplestoplotLabel              matlab.ui.control.Label
        txt_dataset_idx_samples_to_plot  matlab.ui.control.EditField
        led_dataset_complete            matlab.ui.control.Lamp
        pB_dataset_Generate             matlab.ui.control.Button
        LoadeddatasetcompleteLabel      matlab.ui.control.Label
        pB_dataset_Load                 matlab.ui.control.Button
        txt_dataset_name                matlab.ui.control.EditField
        pB_dataset_samples_Plot         matlab.ui.control.Button
    end

    properties (Access = public)
        dir
        Figure
        CallingApp
        flag
        eit_env EIT_env
        eit_dataset EIT_dataset
    end

    methods (Access = public)
        
        function app = Init_values(app)

            %% Init Dropdown items
            app.dD_chamber_typ.Items =app.eit_env.setup.chamber.supported_forms();
            
            app.dD_pattern_func.Items=app.eit_env.setup.pattern.get_generating_func();
            % >>> the pattern list for inj and meas are in setDataGUI()
            
            app.dD_fmdl_solve.Items = app.eit_env.fwd_model.supported_solvers();
            app.dD_fmdl_jac.Items = app.eit_env.fwd_model.supported_jacobians();
            app.dD_fmdl_systmat.Items = app.eit_env.fwd_model.supported_sys_mats();
            app.dD_fmdl_miscperm_sym.Items = app.eit_env.fwd_model.supported_perm_sym();
            
            % Electrode Layouts
            elec_layout= EIT_elec_layout();% dummy
            app.table_chamber_elec_layout.ColumnFormat=elec_layout.get_format_4_gui();
            struct4gui=elec_layout.get_struct_4_gui();
            app.table_chamber_elec_layout.ColumnName= fieldnames(struct4gui);
            app.table_chamber_elec_layout.Data=[struct2cell(struct4gui)]';
            
            % Objects
            object= EIT_object(); %dummy
            app.table_sim_objects.ColumnFormat = object.get_format_4_gui();
            struct4gui = object.get_struct_4_gui();
            app.table_sim_objects.ColumnName= fieldnames(struct4gui);
            app.table_sim_objects.Data =[struct2cell(struct4gui)]';
            
            % User entry
            user_e=UserEntry(); %dummy
            app.table_dataset_user_entry.ColumnFormat = user_e.get_format_4_gui();
            struct4gui = user_e.get_struct_4_gui();
            app.table_dataset_user_entry.ColumnName= replace(fieldnames(struct4gui(1)),'_',' ');
            app.table_dataset_user_entry.Data =[struct2cell(struct4gui)]';
            
            % Slices
            app.tab_rec_slices.Data= {inf inf 0; inf 0 inf; 0 inf inf};
            app.dD_unit_selectValueChanged()

        end

        function app= Init_Paths(app, currentPath)
            %% INIT Paths
            % set the used paths for Saving/Loading functions
            % app.dir.current is Define before the call of this function
            
%             path= mfilename('fullpath');
%             [fPath, fName, fExt] = fileparts(path);
            
            app.dir.current = currentPath;
            
            app.dir.src =path_join(app.dir.current, 'src');
            app.dir.default = path_join(app.dir.src, 'default');
            app.dir.local = path_join(app.dir.src, 'local');
            
            app.dir.env=path_join(app.dir.current, 'env');
            app.dir.sim=path_join(app.dir.env, 'sim');
            app.dir.rec=path_join(app.dir.env, 'rec');
            app.dir.fmdl=path_join(app.dir.env, 'fmdl');
            app.dir.chamber=path_join(app.dir.env, 'chamber');
            
            app.dir.output= path_join(app.dir.current, 'output');
            app.dir.user_entry= path_join(app.dir.output, 'user_entry');
            app.dir.dataset= path_join(app.dir.output, 'ai_datasets');

            % test the paths if not exist they are created
            create_path(app.dir);
            % add the paths to matlab
            add_2_matlabpath(app.dir)
            
        end

        function app= Start_EIDORS_ToolBox(app)
            t=Eidors_toolbox(app.dir.local);
            app.dir.EIDORS=t.local_path;
        end
        
        function plotSlices(app, img ,figName)
            
            if ~strcmp(img.type,'image')
                return;
            end
            
            levels= cell2mat(app.tab_rec_slices.Data);
            for i= 1:size(levels,1)
                img.calc_slices.levels= levels(i,:);
                figName= sprintf('Slice x = %d , y = %d , z = %d', img.calc_slices.levels);
                h= getCurrentFigure_with_figName(figName);
                h=show_slices(img,img.calc_slices.levels);
                [voltage, length,current, conduct]= app.get_units();
                
                lvl= levels(i,:) > 0;
                if sum(lvl)~=2
                    return
                end
                
                if  lvl(1)
                    xlabel(['X ' length]);
                end
                if  lvl(2)
                    ylabel(['Y ' length]);
                end
                if  lvl(3)
                    zlabel(['Z ' length]);
                end
            end
            
        end
        
        
        function plotFem(app, mdl, figName, colorBar)
            
            op=[0,0,0];
            
            if strcmp(mdl.type,'image') % tranparency for images
                mdl.fwd_model.show_fem.alpha_inhomogeneities=app.slider_rec_transparency.Value/100;
            end
            
            if logical(colorBar) % display colorbar
                op(1)= 1;
            end
            
            if app.sw_fem_show_elec_nb.Value % display electrodes numbers
                op(2)=1.012;
            end
            
            h= getCurrentFigure_with_figName(figName);
            h= show_fem(mdl ,op);
            [voltage, length,current, conduct]= app.get_units();
            xlabel(['X ' length]);
            ylabel(['Y ' length]);
            zlabel(['Z ' length]);
            
            if ~app.sw_fem_show_mesh.Value % show mesh 
                set(h,'EdgeColor','none');
            end
       
        end
        
        function app= SetDataGUI(app)
            
            % Slider slice in Z
            chamber_height= app.eit_env.setup.chamber.height();
            if chamber_height~=0
                app.slider_rec_set_slice_z.Limits=[-chamber_height/2,chamber_height/2];
            end
            % Eidors starting path
            app.txt_eidors_path.Value=app.dir.EIDORS;
            
            % Chamber
            app.txt_chamber_name.Value    = app.eit_env.setup.chamber.name ;   
            app.dD_chamber_typ.Value     = app.eit_env.setup.chamber.form ;
            app.dB_chamber_boxX.Value    = app.eit_env.setup.chamber.length();
            app.dB_chamber_boxY.Value    = app.eit_env.setup.chamber.width();
            app.dB_chamber_boxZ.Value    = app.eit_env.setup.chamber.height();
            app.dB_chamber_refinement.Value     = app.eit_env.setup.chamber.femRefinement;
            
            % Electrode layout
            struct4gui= app.eit_env.setup.get_elec_layout_4_gui();
            table_data=[];
            for i=1:length(struct4gui)
                table_data=[table_data; struct2cell(struct4gui(i))'];
            end
            app.table_chamber_elec_layout.Data=table_data;
            
            %fmdl solver
            fmdl= app.eit_env.fwd_model;
            app.txt_fmdl_name.Value= fmdl.name;
            app.dD_fmdl_solve.Value= fmdl.solve;
            app.dD_fmdl_jac.Value= fmdl.jacobian;
            app.dD_fmdl_systmat.Value= fmdl.system_mat;
            app.dD_fmdl_miscperm_sym.Value= fmdl.misc.perm_sym;
            
            %pattern
            %update pattern choices 
            app.dD_pattern_inj_type.Items=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_meas_typ.Items=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_inj_type.ItemsData=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_meas_typ.ItemsData=app.eit_env.setup.pattern.get_patterns();
            
            pattern= app.eit_env.setup.pattern;
            app.dB_pattern_amplitude.Value = pattern.injAmplitude;
            app.dD_pattern_inj_type.Value = pattern.injType;
            app.txt_pattern_inj_special.Value = pattern.injSpecial;
            app.dD_pattern_meas_typ.Value = pattern.measType;
            app.txt_pattern_meas_special.Value =pattern.measSpecial;
            app.txt_pattern_option.Value = pattern.patternOption;
            app.dD_pattern_func.Value = pattern.patternFunc;
            
            % update patterns table
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_inj= [1:size(inj,1)];
            
            app.table_pattern_inj.Data = [list_indx_inj' inj];
 
            inj_indx= str2num(app.dD_pattern_actual_inj_nb.Value);
            app.table_pattern_meas.Data = meas(:,:,inj_indx);


            %Simulation
            % medium
            app.dB_sim_medium_conduct.Value = app.eit_env.sim.mediumConduct;
            % Electrode layout
            struct4gui= app.eit_env.sim.get_objects_4_gui();
            t=[];
            for i=1:length(struct4gui)
                t=[t; struct2cell(struct4gui(i))'];
            end
            app.table_sim_objects.Data = t;
            
            %imdl solver
            imdl=app.eit_env.inv_model;
        
            app.txt_imdl_name.Value=imdl.name;
            app.dD_imdl_solve.Value = imdl.solve;
            app.dD_imdl_RtR_prior.Value =imdl.RtR_prior;
            
%             app.dD_imdl_hyperparam_func.Value = imdl.hyperparameter.func;
%             app.dD_imdl_hyperparam_params.Value = imdl.hyperparameter.parameters;
            app.dD_imdl_hyperparam_val.Value = imdl.hyperparameter.value;
%             app.dD_imdl_jac_bkgnd_func.Value = imdl.jacobian_bkgnd.func;
            app.dD_imdl_jac_bkgnd_val.Value = imdl.jacobian_bkgnd.value;
            app.dD_imdl_reconst_type.Value = imdl.reconst_type;
           
%             app.table_rec_GREIT_results.Data=[struct2cell(EIDORS.sim.GREIT)]';
%             app.table_rec_GREIT_results.ColumnName=fieldnames(EIDORS.sim.GREIT);

            %% Dataset
            % User entry
            user_e=app.eit_dataset.user_entry;
            app.table_dataset_user_entry.ColumnFormat = user_e.get_format_4_gui();
            struct4gui = user_e.get_struct_4_gui();
            app.table_dataset_user_entry.ColumnName= replace(fieldnames(struct4gui(1)),'_',' ');
            app.table_dataset_user_entry.Data =[struct2cell(struct4gui)]';
            
            if app.eit_dataset.is_done()
                app.led_dataset_complete.Color= [0,1,0];
            else
                app.led_dataset_complete.Color= [1,0,0];
            end
            
            app.txt_dataset_name.Value=app.eit_dataset.name;

        end
        function [chamber, elec_layout, pattern, add_text] = get_eit_setup(app)
            % Chamber
            chamber = EIT_chamber( ...
                app.txt_chamber_name.Value, ...
                [app.dB_chamber_boxX.Value,app.dB_chamber_boxY.Value, app.dB_chamber_boxZ.Value], ...
                app.dB_chamber_refinement.Value, ...
                app.dD_chamber_typ.Value);
            
            % Electrode layout
            layout_data= cell2struct(app.table_chamber_elec_layout.Data, ...
                app.table_chamber_elec_layout.ColumnName, 2);
            for i=1:length(layout_data)
                elec_layout(i)= EIT_elec_layout(layout_data(i));           
            end
            %Pattern
            pattern= EIT_pattern( ...
                app.dB_pattern_amplitude.Value, ...
                app.dD_pattern_inj_type.Value, ...
                app.txt_pattern_inj_special.Value, ...
                app.dD_pattern_meas_typ.Value, ...
                app.txt_pattern_meas_special.Value, ...
                app.txt_pattern_option.Value, ...
                app.dD_pattern_func.Value);
           
            add_text='';
        end
        
        
        function [medium, objects] = get_sim_setup(app)
            %% set simulation Medium / Objects
            medium= app.dB_sim_medium_conduct.Value;
            objects_data(:) = cell2struct(app.table_sim_objects.Data, ...app.flag
                app.table_sim_objects.ColumnName, 2);
            
            for i=1:length(objects_data)
                objects(i)= EIT_object(objects_data(i));           
            end
        end
        
        function [voltage, length,current, conduct]= get_units(app)
            %%GET_UNITS returns the actual units as strings 
            val=app.dD_unit_select.Value;
            voltage = '[V]';
            length= '[m]';
            current= '[A]';
            conduct= '[S/m]';            
            if strcmp(val,'2')
                length= '[mm]';
                current= '[mA]';
                conduct= '[mS/mm]';
            end
        end
        
        function help_dialog(app,file)
            %% HELP_DIALOG pop a dialog with the text contain in txt file
            fi=fopen(file,'r');
            txtcell=textscan(fi,'%s','delimiter','\n');
            fclose(fi);
            helpdlg(txtcell{1})
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, currentPath)
            %% Add the folder of THIS FILE and its subfolders
            app.Init_Paths(currentPath);
            
            app.eit_env=EIT_env();
            app.eit_dataset=EIT_dataset();
            
            app.Start_EIDORS_ToolBox();
            app.Init_values();
            
            
            app.SetDataGUI();
            
            % for debug purpose create fmdl
            app.pB_fmdl_BuildButtonPushed();

        end

        % Close request function: EITSoftware
        function EITSoftwareCloseRequest(app, event)
             delete(app);
        end

        % Button pushed function: pB_eit_env_Save
        function pB_eit_env_SavePushed(app, event)
            %
            app.eit_env.name= app.txt_eit_env_name.Value;
            t = datetime('now','TimeZone','local')
            app.eit_env.save(app.dir.env,[datestr(t, 'yyyyMMdd_HHmmss_') , app.eit_env.name, '_eit_env']);
            if app.chB_eit_env_export2py.Value
                app.eit_env.make_mat_file4py(app.dir.env)
            end
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_eit_env_Load
        function pB_eit_env_LoadPushed(app, event)
            app.eit_env.update_from_file(app.dir.env)
            app.SetDataGUI();
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_fmdl_fem_Build
        function pB_fmdl_fem_BuildPushed(app, event)
            [chamber, elec_layout, pattern, add_text] = app.get_eit_setup();            
            % app.FmdlGenerationFunctionDropDown.Value %
            % if needed transfer this to create_fwd_model        
            [fmdl, success]=app.eit_env.create_fwd_model(chamber, elec_layout, add_text);
            if success
                app.SetDataGUI();
                app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
            end
        end

        % Button pushed function: pB_fmdl_Save
        function pB_fmdl_SavePushed(app, event)
            app.eit_env.save(app.dir.fmdl, app.eit_env.fwd_model.name);
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_fmdl_Load
        function pB_fmdl_LoadPushed(app, event)
            app.eit_env.update_from_file(app.dir.fmdl)
            app.SetDataGUI();
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_sim_Save
        function pB_sim_SaveButtonPushed(app, event)
            name= [app.txt_fmdl_name.Value '_sim_results.mat'];
            file=path_join(app.dir.sim, name);
            data_ih = app.eit_env.sim.data_ih.meas;
            data_h = app.eit_env.sim.data_h.meas;
            X= data_ih;
            X_ih= data_ih;
            X_h= data_h;
            eit_env= app.eit_env;
            [file, path] =uiputfile({'*.mat','*.m'}, 'Enter a filename for saving the foward solution', file);
            if file==0
                return;
            end
            file=path_join(path, file);
            save(file, "X", "X_ih", "X_h", "eit_env", '-mat');
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_sim_Load
        function pB_sim_LoadButtonPushed(app, event)
            app.eit_env.update_from_file(app.dir.sim)
            app.SetDataGUI();
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_chamber_Save
        function pB_chamber_SavePushed(app, event)
            app.eit_env.save(app.dir.chamber, app.eit_env.setup.chamber.name);
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_chamber_Load
        function pB_chamber_LoadPushed(app, event)
            % Refactored
            [new_env, success, path]= app.eit_env.load(app.dir.chamber,"");
            if success
                app.eit_env.setup.chamber= new_env.setup.chamber;
                app.SetDataGUI();
            end
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_fmdl_pattern_Build
        function pB_fmdl_pattern_BuildPushed(app, event)
            [chamber, elec_layout, pattern, add_text] = app.get_eit_setup();
            app.eit_env.generate_pattern(pattern, app.cB_fmdl_pattern_hold.Value);
             % set the list of injections
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_inj= [1:size(inj,1)];
            app.dD_pattern_actual_inj_nb.Items= string(num2cell(list_indx_inj));
            app.dD_pattern_actual_inj_nb.Value= "1"; %% reset to injection nr 1
            app.SetDataGUI(); 
        end

        % Button pushed function: pB_chamber_elec_layout_Add
        function pB_chamber_elec_layout_AddPushed(app, event)
            default_elec_layout= EIT_elec_layout();
            struct4gui= default_elec_layout.get_struct_4_gui();            
            app.table_chamber_elec_layout.Data=[app.table_chamber_elec_layout.Data; struct2cell(struct4gui)'];
        end

        % Button pushed function: pB_chamber_elec_layout_Del
        function pB_chamber_elec_layout_DelPushed(app, event)
            app.table_chamber_elec_layout.Data=app.table_chamber_elec_layout.Data(1:end-1,:);
        end

        % Value changed function: dD_pattern_actual_inj_nb
        function dD_pattern_actual_inj_nbValueChanged(app, event)
            app.SetDataGUI();
        end

        % Button pushed function: pB_pattern_Display
        function pB_pattern_DisplayPushed(app, event)

            fmdl=app.eit_env.fwd_model.fmdl;
            
            animated = app.cB_pattern_animated.Value; % value : 0 or 1
            with_meas = app.cB_pattern_with_meas.Value; % value : 0 or 1
            display_all=app.cB_pattern_all_display.Value; % value : 0 or 1
            
            display_func= app.sw_pattern_func_display.Value; % value : "EIDORS Display" or "Custom Display"
            
            switch display_func
                case "EIDORS Display"
                    displayStimOption={'n','y','na','ya'};
                    option= displayStimOption{with_meas+2*animated+1};
                    show_stim_meas_pattern(fmdl,option);
                case "Custom Display"
                    Own_DisplayScript_Pattern(app.eit_env.fwd_model, display_all,animated,with_meas);
            end
            [voltage, length,current, conduct]= app.get_units();
            xlabel(['X ' length]);
            ylabel(['Y ' length]);
            zlabel(['Z ' length]);

        end

        % Button pushed function: pB_sim_Solve
        function pB_sim_SolvePushed(app, event)
            
            % set the solver only when fwd model is created
            app.eit_env.fwd_model.set_solver( ...
                app.txt_fmdl_name.Value, ...
                app.dD_fmdl_solve.Value, ...
                app.dD_fmdl_jac.Value, ...
                app.dD_fmdl_systmat.Value, ...
                app.dD_fmdl_miscperm_sym.Value);
            
            %% set simulation Medium / Objects
            [medium, objects] = app.get_sim_setup();
            app.eit_env.set_sim(medium, objects);
            
            %% solve foward
            app.eit_env.solve_fwd(app.chB_sim_add_obj_in_fem.Value);
                   
%             show_cell(h,EIDORS.sim.iimg.calc_slices.levels,1)
            disp('Generate model with cell: Done!');
            
            app.pB_sim_DisplayPushed()
            
          
            app.SetDataGUI();
        end

        % Button pushed function: pB_sim_objects_Add
        function pB_sim_objects_Adds_Pushed(app, event)
            app.table_sim_objects.Data=[app.table_sim_objects.Data; {'Cell' '[0,0,0]' '[0.1, 0, 0]' '[0.1]'};];
        end

        % Button pushed function: pB_sim_objects_Del
        function pB_sim_objects_Dels_Pushed(app, event)
            app.table_sim_objects.Data=app.table_sim_objects.Data(1:end-1,:);
        end

        % Value changed function: slider_rec_set_slice_z
        function slider_rec_set_slice_zValueChanged(app, event)
            value = app.slider_rec_set_slice_z.Value;
            app.tab_rec_slices.Data=[app.tab_rec_slices.Data; {inf, inf, value}];
            t= ['Slice at Z-height ' num2str(value, '%.3f') ' mm'];
            app.plotSlices(app.eit_env.rec.iimg ,t)
        end

        % Value changed function: slider_rec_transparency
        function slider_rec_transparencyValueChanged(app, event)
            figName= '3D Image of resolved inverse Problem';
            app.plotFem(app.eit_env.rec.iimg, figName, 1);
        end

        % Callback function
        function pB_sim_SavePushed(app, event)
            app.pB_eit_env_Save();
        end

        % Callback function
        function pB_sim_LoadPushed(app, event)
            app.pB_eit_env_Load();
        end

        % Value changed function: sw_fem_show_mesh
        function sw_fem_show_meshValueChanged(app, event)
            app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
        end

        % Value changed function: sw_fem_show_elec_nb
        function sw_fem_show_elec_nbValueChanged(app, event)
            app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
        end

        % Button pushed function: pB_rec_slice_row_add
        function pB_rec_slice_row_addPushed(app, event)
            app.tab_rec_slices.Data=[app.tab_rec_slices.Data; {Inf Inf 0}];
        end

        % Button pushed function: pB_rec_slice_row_del
        function pB_rec_slice_row_delPushed(app, event)
            app.tab_rec_slices.Data=app.tab_rec_slices.Data(1:end-1,:);
        end

        % Button pushed function: pB_rec_Solve
        function pB_rec_SolvePushed(app, event)
            app.eit_env.inv_model.set_inv_solver( ...
                '', ...
                app.dD_imdl_solve.Value, ...
                app.dD_imdl_RtR_prior.Value, ...
                '', ...
                app.dD_imdl_hyperparam_val.Value, ...
                app.dD_imdl_hyperparam_func.Value, ...
                app.dD_imdl_hyperparam_params.Value, ...
                app.dD_imdl_jac_bkgnd_val.Value, ...
                app.dD_imdl_jac_bkgnd_func.Value, ...
                app.dD_imdl_reconst_type.Value);
            
            % Solve
            path= '';
            if app.cB_rec_load_meas.Value==1
                path= app.dir.current;
            end
            app.eit_env.solve_inv(path)
        
            app.pB_rec_DisplayButtonPushed()

        end

        % Value changed function: dD_pattern_func
        function dD_pattern_funcValueChanged(app, event)
            % set the pattren function choosen
            app.eit_env.setup.pattern.init_pattern_func(app.dD_pattern_func.Value);
            app.SetDataGUI();
        end

        % Button pushed function: pB_dataset_user_entry_Save
        function pB_dataset_user_entry_SavePushed(app, event)

            user_entries= cell2struct(app.table_dataset_user_entry.Data, ...
                replace(app.table_dataset_user_entry.ColumnName, ' ', '_'), 2);
        
            d=EIT_dataset();
            d.update(app.eit_env); % save all env in dataset
            
            for i=1%:length(user_entries)
                d.user_entry= UserEntry(user_entries(i));
                name= [d.user_entry.name '_user_entry.mat'];
                d.save(app.dir.user_entry, name);
            end
            app.eit_dataset=d;
            app.SetDataGUI();
        end

        % Button pushed function: pB_dataset_Generate
        function pB_dataset_GeneratePushed(app, event)
            d= EIT_dataset;          
            d.update_from_file(app.dir.user_entry);
            d.generate(app.dir.dataset);
        end

        % Button pushed function: pB_dataset_Load
        function pB_dataset_LoadPushed(app, event)
            app.eit_dataset.update_from_file(app.dir.dataset)
            app.SetDataGUI();
        end

        % Button pushed function: pB_dataset_samples_Plot
        function pB_dataset_samples_PlotButtonPushed(app, event)
            idx=str2num_array(app.txt_dataset_idx_samples_to_plot.Value);
            idx_to_plot=1:length(idx);
            
            if app.chB_dataset_samples_load_idx.Value

                filter= ['*' '.mat'];
                promptText = 'Select file to import test idx'; % text used by loading saving dialogboxes
                filepath= path_join(app.eit_dataset.dir_path, '');

                [filename,folder, success] = uigetfile(filter,promptText,filepath);
                if success
                f= load(path_join(folder,filename));
                idx= f.idx_test + 1;
                end
                idx_to_plot=str2num_array(app.txt_dataset_idx_samples_to_plot.Value);
                idx= idx(idx_to_plot)
            end
            
            [data, idx_data]= app.eit_dataset.load_data('src_data', idx);
            % reorder the data (they are given sorted...)
            [row,col]=find(idx_data.*ones(length(idx),length(idx)) == idx);
            idx_data=idx_data(row);
            data=data(row);
            
            for i= 1:length(idx_data)
                data(i).reset_fmdl(app.eit_dataset.fwd_model.fmdl)
            end
                       
            
            for i= 1:length(idx_to_plot)
                img = data(i).img_ih;
                meas = data(i).data_ih.meas;
                
                subplot(length(idx_to_plot),2,(i-1)*2+1)
                show_fem(img,[1,1,0]);
                title(['Conduct Sample# ' num2str(idx_data(i)) ' (1-based)']);
                
                subplot(length(idx_to_plot),2,i*2)
                plot(meas)
                title(['Voltages Sample# ' num2str(idx_data(i)) ' (1-based)']);
            end 
            
            
        end

        % Button pushed function: pB_dataset_samples_rec
        function pB_dataset_samples_recPushed(app, event)
            idx=str2num_array(app.txt_dataset_idx_samples_to_plot.Value);
            idx_to_plot=1:length(idx);
            
            if app.chB_dataset_samples_load_idx.Value

                filter= ['*' '.mat'];
                promptText = 'Select file to import test idx'; % text used by loading saving dialogboxes
                filepath= path_join(app.eit_dataset.dir_path, '');

                [filename,folder, success] = uigetfile(filter,promptText,filepath);
                if success
                f= load(path_join(folder,filename));
                idx= f.idx_test + 1;
                idx=idx(1:100);
                end
                idx_to_plot=str2num_array(app.txt_dataset_idx_samples_to_plot.Value);

            end
            
            [data, idx_data]= app.eit_dataset.load_data('src_data', idx);
            % reorder the data (they are given sorted...)
            [row,col]=find(idx_data.*ones(length(idx),length(idx)) == idx);
            idx_data=idx_data(row);
            data=data(row);
            
            for i= 1:length(idx_data)
                data(i).reset_fmdl(app.eit_dataset.fwd_model.fmdl)
            end
            
            app.eit_dataset.inv_model.set_inv_solver( ...
                '', ...
                app.dD_imdl_solve.Value, ...
                app.dD_imdl_RtR_prior.Value, ...
                '', ...
                app.dD_imdl_hyperparam_val.Value, ...
                app.dD_imdl_hyperparam_func.Value, ...
                app.dD_imdl_hyperparam_params.Value, ...
                app.dD_imdl_jac_bkgnd_val.Value, ...
                app.dD_imdl_jac_bkgnd_func.Value, ...
                app.dD_imdl_reconst_type.Value);
            
            samples_idx=[]
            for i= 1:length(idx_data)
                % solve inv 
                app.eit_dataset.rec.set_data_meas( data(i).data_ih,  data(i).data_h)        
                app.eit_dataset.solve_inv('')

                img = data(i).img_ih;
                meas = data(i).data_ih.meas;
                
                elem_data(:,i) = app.eit_dataset.rec.iimg.elem_data;
                samples_idx=[samples_idx, num2str(idx_data(i)),'_'];
            end
            
            % trunc 
            if length(samples_idx)>10
                samples_idx= [num2str(idx_data(1)) '-' num2str(idx_data(end))];
            end
            
            inv_solver= app.eit_dataset.inv_model;
            
            if app.chB_dataset_samples_rec_extract_elem_data.Value
                filename= ['Samples' samples_idx 'elems_solved.mat']
                save( path_join(app.eit_dataset.dir_path, filename), 'elem_data', 'inv_solver')
            end
           
           
            for i= 1:length(idx_to_plot)
                % solve inv 
                app.eit_dataset.rec.set_data_meas(data(idx_to_plot(i)).data_ih,  data(idx_to_plot(i)).data_h)
                app.eit_dataset.solve_inv('')

                img = data(idx_to_plot(i)).img_ih;
                
                subplot(length(idx_to_plot),2,(i-1)*2+1)
                show_fem(img,[1,1,0]);
                title(['Conduct Sample# ' num2str(idx_data(idx_to_plot(i))) ' (1-based)']);
                
                subplot(length(idx_to_plot),2,i*2)
                show_fem(app.eit_dataset.rec.iimg,[1,1,0]);
                title(['Reconstruction Sample# ' num2str(idx_data(idx_to_plot(i))) ' (1-based)']);
                
            end


        end

        % Callback function
        function pB_dataset_sample_ShowButtonPushed(app, event)
            idx=str2num_array(app.txt_dataset_idx_samples_to_plot.Value);
            samples= app.eit_dataset.load_data('samples', idx)
        end

        % Button pushed function: pB_fmdl_trunc_injection
        function pB_fmdl_trunc_injectionButtonPushed(app, event)
            inj_range= str2num_array(app.txt_fmdl_pattern_select_inj_to_keep.Value)
            app.eit_env.fwd_model.stimulation=app.eit_env.fwd_model.stimulation(inj_range)
            
             % set the list of injections
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_inj= [1:size(inj,1)];
            app.dD_pattern_actual_inj_nb.Items= string(num2cell(list_indx_inj));
            app.dD_pattern_actual_inj_nb.Value= "1"; %% reset to injection nr 1
            app.SetDataGUI(); 
        end

        % Value changed function: dD_unit_select
        function dD_unit_selectValueChanged(app, event)
            [voltage, length,current, conduct]= get_units(app);
            app.XEditFieldLabel.Text= ['X ' length];
            app.lab_chamber_boxY.Text= ['Y ' length];
            app.lab_chamber_boxZ.Text= ['Z ' length];
            app.lab_chamber_refinement.Text  = ['FEMRefinement ' length];
            app.AmplitudeALabel.Text = ['Amplitude ' current];
            app.MediumconductivityEditFieldLabel.Text = ['Medium conductivity ' conduct];


        end

        % Value changed function: sw_pattern_func_display
        function sw_pattern_func_displayValueChanged(app, event)
            display_func= app.sw_pattern_func_display.Value; % value : "EIDORS Display" or "Custom Display"
            
            switch display_func
                case "EIDORS Display"
                   app.cB_pattern_all_display.Enable = false;
                case "Custom Display"
                    app.cB_pattern_all_display.Enable = true;
            end
            
        end

        % Button pushed function: pB_fmdl_Build
        function pB_fmdl_BuildButtonPushed(app, event)
            app.pB_fmdl_fem_BuildPushed()
            app.pB_fmdl_pattern_BuildPushed()
        end

        % Button pushed function: pB_sim_Display
        function pB_sim_DisplayPushed(app, event)
            %%Plot 3D images
            figName= '3D Image of homogenious conductivity';
            app.plotFem(app.eit_env.sim.img_h, figName, 1);
            figName= '3D Image of inhomogenious conductivity';
            app.plotFem(app.eit_env.sim.img_ih, figName, 1);
            
            %& Plot measurements
            figName= 'Simulated EIT measurements';
            h= getCurrentFigure_with_figName(figName);
            data_ih = app.eit_env.sim.data_ih.meas;
            data_h = app.eit_env.sim.data_h.meas;
            plot([data_ih, data_h, data_ih-data_h]);
            legend({'ih', 'h', 'diff'}, "Location","northeast");
            [voltage, length,current, conduct]= app.get_units();
            xlabel('Measurements #');
            ylabel(['Voltages ' voltage]);
            title('Forward Solution')
        end

        % Button pushed function: pB_dataset_user_entry_Readme
        function pB_dataset_user_entry_ReadmeButtonPushed(app, event)
            app.help_dialog('info_ai_dataset.txt')
        end

        % Button pushed function: pB_chamber_elec_layout_Readme
        function pB_chamber_elec_layout_ReadmeButtonPushed(app, event)
            app.help_dialog('info_elec_layout.txt')
        end

        % Button pushed function: pB_sim_objects_Readme
        function pB_sim_objects_ReadmeButtonPushed(app, event)
            app.help_dialog('info_sim_objects.txt')
        end

        % Button pushed function: pB_rec_Save
        function pB_rec_SaveButtonPushed(app, event)
            %
            app.eit_env.name= app.txt_eit_env_name.Value;
            t = datetime('now','TimeZone','local')
            app.eit_env.save(app.dir.rec,[datestr(t, 'yyyyMMdd_HHmmss_') ,'_rec_results']);
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_rec_Load
        function pB_rec_LoadButtonPushed(app, event)
            app.eit_env.update_from_file(app.dir.rec)
            app.SetDataGUI();
            figure(app.EITSoftware);
        end

        % Button pushed function: pB_rec_Display
        function pB_rec_DisplayButtonPushed(app, event)
            figName= '3D Image of resolved inverse Problem';
            app.plotFem(app.eit_env.rec.iimg, figName, 1);
            app.plotSlices(app.eit_env.rec.iimg ,figName)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EITSoftware = uifigure('Visible', 'off');
            app.EITSoftware.Name = 'EIT - Modelling, Simulation and Reconstruction Software';
            app.EITSoftware.Icon = 'EIT.png';
            app.EITSoftware.CloseRequestFcn = createCallbackFcn(app, @EITSoftwareCloseRequest, true);
            app.EITSoftwareGrid = uigridlayout(app.EITSoftware);
            app.TabMain = uitabgroup(app.EITSoftwareGrid);
            app.GlobalSettingsTab = uitab(app.TabMain);
            app.GlobalSettingsTab.Title = 'Global Settings';
            app.GlobalSettingsTabGrid = uigridlayout(app.GlobalSettingsTab);
            app.GeneralSettingsPanel = uipanel(app.GlobalSettingsTabGrid);
            app.GeneralSettingsPanelGrid = uigridlayout(app.GeneralSettingsPanel);
            app.dD_unit_select = uidropdown(app.GeneralSettingsPanelGrid);
            app.dD_unit_select.Items = {'"V, A, m, S/m"', '"V, mA, mm, mS/mm"'};
            app.dD_unit_select.ItemsData = {'1', '2'};
            app.dD_unit_select.ValueChangedFcn = createCallbackFcn(app, @dD_unit_selectValueChanged, true);
            app.dD_unit_select.Value = '2';
            app.UnitsLabel = uilabel(app.GeneralSettingsPanelGrid);
            app.UnitsLabel.Text = 'Units';
            app.txt_eidors_path = uieditfield(app.GeneralSettingsPanelGrid, 'text');
            app.PathEIDORSEditFieldLabel_2 = uilabel(app.GeneralSettingsPanelGrid);
            app.PathEIDORSEditFieldLabel_2.Text = 'Path EIDORS';
            app.EITEnvControlPanel = uipanel(app.GlobalSettingsTabGrid);
            app.EITEnvControlPanelGrid = uigridlayout(app.EITEnvControlPanel);
            app.chB_eit_env_export2py = uicheckbox(app.EITEnvControlPanelGrid);
            app.chB_eit_env_export2py.Text = 'export 2py';
            app.pB_eit_env_Load = uibutton(app.EITEnvControlPanelGrid, 'push');
            app.pB_eit_env_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_eit_env_LoadPushed, true);
            app.pB_eit_env_Load.Text = 'Load';
            app.pB_eit_env_Save = uibutton(app.EITEnvControlPanelGrid, 'push');
            app.pB_eit_env_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_eit_env_SavePushed, true);
            app.pB_eit_env_Save.Tooltip = {'Save the complete environement '};
            app.pB_eit_env_Save.Text = 'Save';
            app.txt_eit_env_name = uieditfield(app.EITEnvControlPanelGrid, 'text');
            app.txt_eit_env_name.Value = 'eit_env_defaultname';
            app.lab_eit_env_name = uilabel(app.EITEnvControlPanelGrid);
            app.lab_eit_env_name.Text = 'Name of the EIT enviromnent';
            app.Title = uitextarea(app.GlobalSettingsTabGrid);
            app.Title.Editable = 'off';
            app.Title.Value = {'Modeling, Simulation and Reconstruction Software for Electrical Impedance Tomography (EIT) setup'; '- based on the EIDORS-toolbox -'; 'TU Braunschweig, Institute of Microtechnology - David Metz - V2.0'};
            app.ForwardModelTab = uitab(app.TabMain);
            app.ForwardModelTab.Title = 'Forward Model';
            app.ForwardModelTabGrid = uigridlayout(app.ForwardModelTab);
            app.FwdModelGenerationPanel = uipanel(app.ForwardModelTabGrid);
            app.FwdModelGenerationPanelGrid = uigridlayout(app.FwdModelGenerationPanel);
            app.pB_fmdl_fem_Build = uibutton(app.FwdModelGenerationPanelGrid, 'push');
            app.pB_fmdl_fem_Build.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_fem_BuildPushed, true);
            app.pB_fmdl_fem_Build.Text = 'Build/Display FEM';
            app.pB_fmdl_Load = uibutton(app.FwdModelGenerationPanelGrid, 'push');
            app.pB_fmdl_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_LoadPushed, true);
            app.pB_fmdl_Load.Text = 'Load';
            app.pB_fmdl_Save = uibutton(app.FwdModelGenerationPanelGrid, 'push');
            app.pB_fmdl_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_SavePushed, true);
            app.pB_fmdl_Save.Text = 'Save';
            app.FEMDisplayControlPanel = uipanel(app.FwdModelGenerationPanelGrid);
            app.FEMDisplayControlPanel.Title = 'FEM display options';
            app.FEMDisplayControlPanelGrid = uigridlayout(app.FEMDisplayControlPanel);
            app.cB_fem_show_mesh_quality = uicheckbox(app.FEMDisplayControlPanelGrid);
            app.cB_fem_show_mesh_quality.Enable = 'off';
            app.cB_fem_show_mesh_quality.Text = 'show mesh quality';
            app.sw_fem_show_mesh = uiswitch(app.FEMDisplayControlPanelGrid, 'slider');
            app.sw_fem_show_mesh.Items = {'Show', 'Hide'};
            app.sw_fem_show_mesh.ItemsData = [1 0];
            app.sw_fem_show_mesh.ValueChangedFcn = createCallbackFcn(app, @sw_fem_show_meshValueChanged, true);
            app.sw_fem_show_mesh.Value = 1;
            app.MeshLabel = uilabel(app.FEMDisplayControlPanelGrid);
            app.MeshLabel.Text = 'Mesh';
            app.sw_fem_show_elec_nb = uiswitch(app.FEMDisplayControlPanelGrid, 'slider');
            app.sw_fem_show_elec_nb.Items = {'Show', 'Hide'};
            app.sw_fem_show_elec_nb.ItemsData = [1 0];
            app.sw_fem_show_elec_nb.ValueChangedFcn = createCallbackFcn(app, @sw_fem_show_elec_nbValueChanged, true);
            app.sw_fem_show_elec_nb.Value = 1;
            app.ElectrodesLabel = uilabel(app.FEMDisplayControlPanelGrid);
            app.ElectrodesLabel.Text = 'Electrodes #';
            app.txt_fmdl_name = uieditfield(app.FwdModelGenerationPanelGrid, 'text');
            app.txt_fmdl_name.Value = 'fwd_model_defaultname';
            app.NameoftheForwardmodelLabel = uilabel(app.FwdModelGenerationPanelGrid);
            app.NameoftheForwardmodelLabel.Text = 'Name of the Forward model';
            app.pB_fmdl_pattern_Build = uibutton(app.FwdModelGenerationPanelGrid, 'push');
            app.pB_fmdl_pattern_Build.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_pattern_BuildPushed, true);
            app.pB_fmdl_pattern_Build.Text = 'Build patterns';
            app.cB_fmdl_pattern_hold = uicheckbox(app.FwdModelGenerationPanelGrid);
            app.cB_fmdl_pattern_hold.Text = 'hold existing Injection pattern';
            app.PatternDisplayControlPanel = uipanel(app.FwdModelGenerationPanelGrid);
            app.PatternDisplayControlPanel.Title = 'Pattern display options';
            app.PatternDisplayControlPanelGrid = uigridlayout(app.PatternDisplayControlPanel);
            app.pB_pattern_Display = uibutton(app.PatternDisplayControlPanelGrid, 'push');
            app.pB_pattern_Display.ButtonPushedFcn = createCallbackFcn(app, @pB_pattern_DisplayPushed, true);
            app.pB_pattern_Display.Text = 'Display Injection Pattern';
            app.cB_pattern_with_meas = uicheckbox(app.PatternDisplayControlPanelGrid);
            app.cB_pattern_with_meas.Text = 'Display Measurements Pattern';
            app.cB_pattern_animated = uicheckbox(app.PatternDisplayControlPanelGrid);
            app.cB_pattern_animated.Tooltip = {'if noct checked type a key to display next injection/measurements'};
            app.cB_pattern_animated.Text = 'Animated';
            app.cB_pattern_animated.Value = true;
            app.cB_pattern_all_display = uicheckbox(app.PatternDisplayControlPanelGrid);
            app.cB_pattern_all_display.Text = 'Hold all inj display';
            app.sw_pattern_func_display = uiswitch(app.PatternDisplayControlPanelGrid, 'slider');
            app.sw_pattern_func_display.Items = {'EIDORS Display', 'Custom Display'};
            app.sw_pattern_func_display.ItemsData = {'EIDORS Display', 'Custom Display'};
            app.sw_pattern_func_display.ValueChangedFcn = createCallbackFcn(app, @sw_pattern_func_displayValueChanged, true);
            app.sw_pattern_func_display.Value = 'EIDORS Display';
            app.pB_fmdl_trunc_injection = uibutton(app.FwdModelGenerationPanelGrid, 'push');
            app.pB_fmdl_trunc_injection.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_trunc_injectionButtonPushed, true);
            app.pB_fmdl_trunc_injection.Text = 'Trunc injections';
            app.InjectionrangetokeepLabel = uilabel(app.FwdModelGenerationPanelGrid);
            app.InjectionrangetokeepLabel.Text = 'Injection range to keep';
            app.txt_fmdl_pattern_select_inj_to_keep = uieditfield(app.FwdModelGenerationPanelGrid, 'text');
            app.txt_fmdl_pattern_select_inj_to_keep.Tooltip = {'Enet a range:'; '- [1,2,3]'; '- [1:10]'; '- [1,2,5:10]'};
            app.txt_fmdl_pattern_select_inj_to_keep.Value = '[1 2]';
            app.pB_fmdl_Build = uibutton(app.FwdModelGenerationPanelGrid, 'push');
            app.pB_fmdl_Build.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_BuildButtonPushed, true);
            app.pB_fmdl_Build.Text = 'Build Forward Model';
            app.ForwardModelTabGroup = uitabgroup(app.ForwardModelTabGrid);
            app.ChamberTab = uitab(app.ForwardModelTabGroup);
            app.ChamberTab.Title = 'Chamber';
            app.ChamberTabGrid = uigridlayout(app.ChamberTab);
            app.ElecLayoutPanel = uipanel(app.ChamberTabGrid);
            app.ElecLayoutPanel.Title = 'Electrode layouts';
            app.ElecLayoutPanelGrid = uigridlayout(app.ElecLayoutPanel);
            app.pB_chamber_elec_layout_Readme = uibutton(app.ElecLayoutPanelGrid, 'push');
            app.pB_chamber_elec_layout_Readme.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_elec_layout_ReadmeButtonPushed, true);
            app.pB_chamber_elec_layout_Readme.Tooltip = {''};
            app.pB_chamber_elec_layout_Readme.Text = 'Readme';
            app.pB_chamber_elec_layout_Del = uibutton(app.ElecLayoutPanelGrid, 'push');
            app.pB_chamber_elec_layout_Del.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_elec_layout_DelPushed, true);
            app.pB_chamber_elec_layout_Del.Tooltip = {'delete last elec layout'};
            app.pB_chamber_elec_layout_Del.Text = '-';
            app.pB_chamber_elec_layout_Add = uibutton(app.ElecLayoutPanelGrid, 'push');
            app.pB_chamber_elec_layout_Add.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_elec_layout_AddPushed, true);
            app.pB_chamber_elec_layout_Add.Tooltip = {'add elec layout'};
            app.pB_chamber_elec_layout_Add.Text = '+';
            app.table_chamber_elec_layout = uitable(app.ElecLayoutPanelGrid);
            app.table_chamber_elec_layout.ColumnName = {'Design'; 'Diameter'; 'Number'; 'Position'; 'Form'; 'Diameter_Width'; 'Height'; ''};
            app.table_chamber_elec_layout.RowName = {};
            app.table_chamber_elec_layout.ColumnEditable = true;
            app.pB_chamber_Save = uibutton(app.ChamberTabGrid, 'push');
            app.pB_chamber_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_SavePushed, true);
            app.pB_chamber_Save.Tooltip = {'save a chamber design'};
            app.pB_chamber_Save.Text = 'Save';
            app.pB_chamber_Load = uibutton(app.ChamberTabGrid, 'push');
            app.pB_chamber_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_LoadPushed, true);
            app.pB_chamber_Load.Tooltip = {'load a chamber design previously saved'};
            app.pB_chamber_Load.Text = 'Load';
            app.BodyPanel = uipanel(app.ChamberTabGrid);
            app.BodyPanel.Title = 'Body';
            app.BodyPanelGrid = uigridlayout(app.BodyPanel);
            app.dB_chamber_refinement = uieditfield(app.BodyPanelGrid, 'numeric');
            app.dB_chamber_refinement.Value = 0.3;
            app.lab_chamber_refinement = uilabel(app.BodyPanelGrid);
            app.lab_chamber_refinement.Text = 'FEM Refinement';
            app.dB_chamber_boxZ = uieditfield(app.BodyPanelGrid, 'numeric');
            app.dB_chamber_boxZ.Value = 1;
            app.lab_chamber_boxZ = uilabel(app.BodyPanelGrid);
            app.lab_chamber_boxZ.Text = 'Z';
            app.dB_chamber_boxY = uieditfield(app.BodyPanelGrid, 'numeric');
            app.lab_chamber_boxY = uilabel(app.BodyPanelGrid);
            app.lab_chamber_boxY.Text = 'Y';
            app.dD_chamber_typ = uidropdown(app.BodyPanelGrid);
            app.dD_chamber_typ.Items = {'Cylinder', 'Cubic', '2D_Circ', ''};
            app.dD_chamber_typ.Value = 'Cylinder';
            app.lab_chamber_typ = uilabel(app.BodyPanelGrid);
            app.lab_chamber_typ.Text = 'Typ';
            app.dB_chamber_boxX = uieditfield(app.BodyPanelGrid, 'numeric');
            app.dB_chamber_boxX.Value = 1;
            app.XEditFieldLabel = uilabel(app.BodyPanelGrid);
            app.XEditFieldLabel.Text = 'X';
            app.txt_chamber_name = uieditfield(app.ChamberTabGrid, 'text');
            app.txt_chamber_name.Value = 'ChamberDesign_defaultname';
            app.NameofthechamberLabel = uilabel(app.ChamberTabGrid);
            app.NameofthechamberLabel.Text = 'Name of the chamber';
            app.PatternTab = uitab(app.ForwardModelTabGroup);
            app.PatternTab.Title = 'Pattern';
            app.PatternTabGrid = uigridlayout(app.PatternTab);
            app.PatterncontrolPanel = uipanel(app.PatternTabGrid);
            app.PatterncontrolPanel.Title = 'Pattern parameter';
            app.PatterncontrolPanelGrid = uigridlayout(app.PatterncontrolPanel);
            app.lab_pattern_option_info = uitextarea(app.PatterncontrolPanelGrid);
            app.lab_pattern_option_info.Editable = 'off';
            app.lab_pattern_option_info.Value = {'if contradictory options are specified, only the last applies'; '%       ''channel_voltages'''; '%       -> get only the voltages at electrodes'; '%      ''no_meas_current'' / ''meas_current'''; '%         -> do / don''t make mesurements on current carrying electrodes'; '%         -> to not make measurements on the electrodes next to the current carrying,'; '%            use ''no_meas_current_next1'''; '%         -> to not make measurements on the two electrodes next to the current carrying,'; '%            use ''no_meas_current_next2'''; '%         -> to not make measurements on the three electrodes next to the current carrying,'; '%            use ''no_meas_current_next3'''; '%      ''rotate_meas'' / ''no_rotate_meas'''; '%         -> do / don''t rotate measurements with stimulation pattern'; '%      ''do_redundant'' / ''no_redundant'''; '%         -> do / don''t make reciprocally redundant measures'; '%      ''balance_inj'' / ''no_balance_inj'''; '%         -> do / don''t draw current from all electrodes so total'; '%            injection is zero (useful for mono patterns)'; '%      ''balance_meas'' / ''no_balance_meas'''; '%         -> do / don''t subtrant measurement from all electrodes so total'; '%            average measurement is zero (useful for mono patterns)'};
            app.dB_pattern_amplitude = uieditfield(app.PatterncontrolPanelGrid, 'numeric');
            app.dB_pattern_amplitude.Value = 1;
            app.AmplitudeALabel = uilabel(app.PatterncontrolPanelGrid);
            app.AmplitudeALabel.Text = 'Amplitude [A]';
            app.dD_pattern_func = uidropdown(app.PatterncontrolPanelGrid);
            app.dD_pattern_func.Items = {'mk_stim_pattern(EIDORS)', 'mk_stim_pattern_Array', 'mk_stim_3Dpattern'};
            app.dD_pattern_func.ValueChangedFcn = createCallbackFcn(app, @dD_pattern_funcValueChanged, true);
            app.dD_pattern_func.Value = 'mk_stim_pattern(EIDORS)';
            app.PatterningfunctionLabel = uilabel(app.PatterncontrolPanelGrid);
            app.PatterningfunctionLabel.Text = 'Patterning function';
            app.dD_pattern_inj_type = uidropdown(app.PatterncontrolPanelGrid);
            app.dD_pattern_inj_type.Items = {'adjacent (EIDORS)', 'opposite (EIDORS)', 'user defined (EIDORS)', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op'};
            app.dD_pattern_inj_type.ItemsData = {'{ad}', '{op}', 'user defined', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_inj_type.Value = '{ad}';
            app.InjectionpatternLabel = uilabel(app.PatterncontrolPanelGrid);
            app.InjectionpatternLabel.Text = 'Injection pattern';
            app.txt_pattern_inj_special = uieditfield(app.PatterncontrolPanelGrid, 'text');
            app.txt_pattern_inj_special.Tooltip = {'First pattern is [x+1,y+1]'};
            app.txt_pattern_inj_special.Value = '[1 2]';
            app.InjuserpatternLabel = uilabel(app.PatterncontrolPanelGrid);
            app.InjuserpatternLabel.Text = 'Inj. user pattern';
            app.dD_pattern_meas_typ = uidropdown(app.PatterncontrolPanelGrid);
            app.dD_pattern_meas_typ.Items = {'adjacent (EIDORS)', 'opposite (EIDORS)', 'user defined (EIDORS)', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_meas_typ.ItemsData = {'{ad}', '{op}', 'user defined', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_meas_typ.Value = '{ad}';
            app.MeasurementpatternLabel = uilabel(app.PatterncontrolPanelGrid);
            app.MeasurementpatternLabel.Text = 'Measurement pattern';
            app.txt_pattern_meas_special = uieditfield(app.PatterncontrolPanelGrid, 'text');
            app.txt_pattern_meas_special.Tooltip = {'First pattern is [x+1,y+1]'};
            app.txt_pattern_meas_special.Value = '[1 2]';
            app.MeasuserpatternLabel = uilabel(app.PatterncontrolPanelGrid);
            app.MeasuserpatternLabel.Text = 'Meas. user pattern';
            app.txt_pattern_option = uitextarea(app.PatterncontrolPanelGrid);
            app.txt_pattern_option.Tooltip = {'if contradictory options are specified, only the last applies'; '%       ''channel_voltages'''; '%       -> get only the voltages at electrodes'; '%      ''no_meas_current'' / ''meas_current'''; '%         -> do / don''t make mesurements on current carrying electrodes'; '%         -> to not make measurements on the electrodes next to the current carrying,'; '%            use ''no_meas_current_next1'''; '%         -> to not make measurements on the two electrodes next to the current carrying,'; '%            use ''no_meas_current_next2'''; '%         -> to not make measurements on the three electrodes next to the current carrying,'; '%            use ''no_meas_current_next3'''; '%      ''rotate_meas'' / ''no_rotate_meas'''; '%         -> do / don''t rotate measurements with stimulation pattern'; '%      ''do_redundant'' / ''no_redundant'''; '%         -> do / don''t make reciprocally redundant measures'; '%      ''balance_inj'' / ''no_balance_inj'''; '%         -> do / don''t draw current from all electrodes so total'; '%            injection is zero (useful for mono patterns)'; '%      ''balance_meas'' / ''no_balance_meas'''; '%         -> do / don''t subtrant measurement from all electrodes so total'; '%            average measurement is zero (useful for mono patterns)'};
            app.txt_pattern_option.Value = {'meas_current'};
            app.OptionsLabel = uilabel(app.PatterncontrolPanelGrid);
            app.OptionsLabel.Text = 'Options';
            app.MeasPatternPanel = uipanel(app.PatternTabGrid);
            app.MeasPatternPanel.Title = 'Meas Pattern';
            app.MeasPatternPanelGrid = uigridlayout(app.MeasPatternPanel);
            app.table_pattern_meas = uitable(app.MeasPatternPanelGrid);
            app.table_pattern_meas.ColumnName = {'Meas -'; 'Meas +'};
            app.table_pattern_meas.RowName = {};
            app.InjectionPatternPanel = uipanel(app.PatternTabGrid);
            app.InjectionPatternPanel.Title = 'Injection Pattern';
            app.InjectionPatternPanelGrid = uigridlayout(app.InjectionPatternPanel);
            app.table_pattern_inj = uitable(app.InjectionPatternPanelGrid);
            app.table_pattern_inj.ColumnName = {'nr'; 'Inj-'; 'Inj+'};
            app.table_pattern_inj.RowName = {};
            app.dD_pattern_actual_inj_nb = uidropdown(app.PatternTabGrid);
            app.dD_pattern_actual_inj_nb.Items = {'1', ''};
            app.dD_pattern_actual_inj_nb.ValueChangedFcn = createCallbackFcn(app, @dD_pattern_actual_inj_nbValueChanged, true);
            app.dD_pattern_actual_inj_nb.Value = '1';
            app.InjectionNrDropDownLabel = uilabel(app.PatternTabGrid);
            app.InjectionNrDropDownLabel.Text = 'Injection Nr.';
            app.SolveForwardTab = uitab(app.TabMain);
            app.SolveForwardTab.Title = 'Solve Forward';
            app.SolveForwardTabGrid = uigridlayout(app.SolveForwardTab);
            app.Panel_sim_controls = uipanel(app.SolveForwardTabGrid);
            app.Panel_sim_controls_grid = uigridlayout(app.Panel_sim_controls);
            app.chB_sim_add_obj_in_fem = uicheckbox(app.Panel_sim_controls_grid);
            app.chB_sim_add_obj_in_fem.Text = 'Add object in FEM';
            app.pB_sim_Solve = uibutton(app.Panel_sim_controls_grid, 'push');
            app.pB_sim_Solve.ButtonPushedFcn = createCallbackFcn(app, @pB_sim_SolvePushed, true);
            app.pB_sim_Solve.Tooltip = {'Solve the forward problem with the previously deifine forward model!'};
            app.pB_sim_Solve.Text = 'Solve foward problem';
            app.pB_sim_Display = uibutton(app.Panel_sim_controls_grid, 'push');
            app.pB_sim_Display.ButtonPushedFcn = createCallbackFcn(app, @pB_sim_DisplayPushed, true);
            app.pB_sim_Display.Tooltip = {'Solve the forward problem with the previously deifine forward model!'};
            app.pB_sim_Display.Text = 'Display Solution';
            app.pB_sim_Load = uibutton(app.Panel_sim_controls_grid, 'push');
            app.pB_sim_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_sim_LoadButtonPushed, true);
            app.pB_sim_Load.Text = 'Load';
            app.pB_sim_Save = uibutton(app.Panel_sim_controls_grid, 'push');
            app.pB_sim_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_sim_SaveButtonPushed, true);
            app.pB_sim_Save.Text = 'Save';
            app.ConductivityChamberControlPanel = uipanel(app.SolveForwardTabGrid);
            app.ConductivityChamberControlPanel.Title = 'Settings of the conductivity in the chamber';
            app.ConductivityChamberControlPanelGrid = uigridlayout(app.ConductivityChamberControlPanel);
            app.ObjectsPanel = uipanel(app.ConductivityChamberControlPanelGrid);
            app.ObjectsPanel.Title = 'Objects';
            app.ObjectsPanelGrid = uigridlayout(app.ObjectsPanel);
            app.pB_sim_objects_Del = uibutton(app.ObjectsPanelGrid, 'push');
            app.pB_sim_objects_Del.ButtonPushedFcn = createCallbackFcn(app, @pB_sim_objects_Dels_Pushed, true);
            app.pB_sim_objects_Del.Tooltip = {'delete last object'};
            app.pB_sim_objects_Del.Text = '-';
            app.pB_sim_objects_Add = uibutton(app.ObjectsPanelGrid, 'push');
            app.pB_sim_objects_Add.ButtonPushedFcn = createCallbackFcn(app, @pB_sim_objects_Adds_Pushed, true);
            app.pB_sim_objects_Add.Tooltip = {'add an object'};
            app.pB_sim_objects_Add.Text = '+';
            app.table_sim_objects = uitable(app.ObjectsPanelGrid);
            app.table_sim_objects.ColumnName = {'PosX'; 'PosY'; 'PosZ'; 'Radius'};
            app.table_sim_objects.RowName = {};
            app.table_sim_objects.ColumnEditable = true;
            app.pB_sim_objects_Readme = uibutton(app.ObjectsPanelGrid, 'push');
            app.pB_sim_objects_Readme.ButtonPushedFcn = createCallbackFcn(app, @pB_sim_objects_ReadmeButtonPushed, true);
            app.pB_sim_objects_Readme.Tooltip = {''};
            app.pB_sim_objects_Readme.Text = 'Readme';
            app.dB_sim_medium_conduct = uieditfield(app.ConductivityChamberControlPanelGrid, 'numeric');
            app.dB_sim_medium_conduct.Tooltip = {'Medum conductivity in the chamber in ...'; ''};
            app.dB_sim_medium_conduct.Value = 1;
            app.MediumconductivityEditFieldLabel = uilabel(app.ConductivityChamberControlPanelGrid);
            app.MediumconductivityEditFieldLabel.Text = 'Medium conductivity';
            app.FMDLSolverControlPanel = uipanel(app.SolveForwardTabGrid);
            app.FMDLSolverControlPanel.Title = 'EIDORS solving parameters';
            app.FMDLSolverControlPanelGrid = uigridlayout(app.FMDLSolverControlPanel);
            app.SolveLabel = uilabel(app.FMDLSolverControlPanelGrid);
            app.SolveLabel.Text = 'Solve';
            app.dD_fmdl_solve = uidropdown(app.FMDLSolverControlPanelGrid);
            app.dD_fmdl_solve.Items = {'eidors_default', 'fwd_solve_1st_order', 'aa_fwd_solve'};
            app.dD_fmdl_solve.Value = 'eidors_default';
            app.JacobianLabel = uilabel(app.FMDLSolverControlPanelGrid);
            app.JacobianLabel.Text = 'Jacobian';
            app.dD_fmdl_jac = uidropdown(app.FMDLSolverControlPanelGrid);
            app.dD_fmdl_jac.Items = {'eidors_default', 'jacobian_adjoint', 'aa_calc_jacobian', ''};
            app.dD_fmdl_jac.Value = 'eidors_default';
            app.System_matLabel = uilabel(app.FMDLSolverControlPanelGrid);
            app.System_matLabel.Text = 'System_mat';
            app.dD_fmdl_systmat = uidropdown(app.FMDLSolverControlPanelGrid);
            app.dD_fmdl_systmat.Items = {'eidors_default', 'system_mat_1st_order', 'aa_calc_system_mat', ''};
            app.dD_fmdl_systmat.Value = 'eidors_default';
            app.Miscperm_symLabel = uilabel(app.FMDLSolverControlPanelGrid);
            app.Miscperm_symLabel.Text = 'Misc.perm_sym';
            app.dD_fmdl_miscperm_sym = uidropdown(app.FMDLSolverControlPanelGrid);
            app.dD_fmdl_miscperm_sym.Items = {'''{n}'''};
            app.dD_fmdl_miscperm_sym.Value = '''{n}''';
            app.SolveInverseTab = uitab(app.TabMain);
            app.SolveInverseTab.Title = 'Solve Inverse';
            app.SolveInverseTabGrid = uigridlayout(app.SolveInverseTab);
            app.ReconstructionControlPanel = uipanel(app.SolveInverseTabGrid);
            app.ReconstructionControlPanelGrid = uigridlayout(app.ReconstructionControlPanel);
            app.RecSlicesPanel = uipanel(app.ReconstructionControlPanelGrid);
            app.RecSlicesPanel.Title = 'Slices for eval';
            app.RecSlicesPanelGrid = uigridlayout(app.RecSlicesPanel);
            app.pB_rec_slice_row_del = uibutton(app.RecSlicesPanelGrid, 'push');
            app.pB_rec_slice_row_del.ButtonPushedFcn = createCallbackFcn(app, @pB_rec_slice_row_delPushed, true);
            app.pB_rec_slice_row_del.Text = '-';
            app.pB_rec_slice_row_add = uibutton(app.RecSlicesPanelGrid, 'push');
            app.pB_rec_slice_row_add.ButtonPushedFcn = createCallbackFcn(app, @pB_rec_slice_row_addPushed, true);
            app.pB_rec_slice_row_add.Text = '+';
            app.tab_rec_slices = uitable(app.RecSlicesPanelGrid);
            app.tab_rec_slices.ColumnName = {'x='; 'y='; 'z='};
            app.tab_rec_slices.RowName = {};
            app.tab_rec_slices.ColumnEditable = true;
            app.slider_rec_set_slice_z = uislider(app.RecSlicesPanelGrid);
            app.slider_rec_set_slice_z.ValueChangedFcn = createCallbackFcn(app, @slider_rec_set_slice_zValueChanged, true);
            app.slider_rec_set_slice_z.Value = 50;
            app.SliceZHeightSliderLabel = uilabel(app.RecSlicesPanelGrid);
            app.SliceZHeightSliderLabel.Text = 'Slice Z-Height';
            app.cB_rec_load_meas = uicheckbox(app.ReconstructionControlPanelGrid);
            app.cB_rec_load_meas.Text = 'Load meas';
            app.pB_rec_Solve = uibutton(app.ReconstructionControlPanelGrid, 'push');
            app.pB_rec_Solve.ButtonPushedFcn = createCallbackFcn(app, @pB_rec_SolvePushed, true);
            app.pB_rec_Solve.Text = 'Build,solve and display inverse model';
            app.slider_rec_transparency = uislider(app.ReconstructionControlPanelGrid);
            app.slider_rec_transparency.ValueChangedFcn = createCallbackFcn(app, @slider_rec_transparencyValueChanged, true);
            app.slider_rec_transparency.Value = 100;
            app.TransparencyimgLabel = uilabel(app.ReconstructionControlPanelGrid);
            app.TransparencyimgLabel.Text = 'Transparency img';
            app.pB_rec_Display = uibutton(app.ReconstructionControlPanelGrid, 'push');
            app.pB_rec_Display.ButtonPushedFcn = createCallbackFcn(app, @pB_rec_DisplayButtonPushed, true);
            app.pB_rec_Display.Tooltip = {'Solve the forward problem with the previously deifine forward model!'};
            app.pB_rec_Display.Text = 'Display Solution';
            app.pB_rec_Load = uibutton(app.ReconstructionControlPanelGrid, 'push');
            app.pB_rec_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_rec_LoadButtonPushed, true);
            app.pB_rec_Load.Text = 'Load';
            app.pB_rec_Save = uibutton(app.ReconstructionControlPanelGrid, 'push');
            app.pB_rec_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_rec_SaveButtonPushed, true);
            app.pB_rec_Save.Text = 'Save';
            app.table_rec_GREIT_results = uitable(app.SolveInverseTabGrid);
            app.table_rec_GREIT_results.ColumnName = {'AmpR'; 'PosError'; 'Res'; 'ShapeDef'; 'Ringing'};
            app.table_rec_GREIT_results.RowName = {};
            app.table_rec_GREIT_results.ColumnEditable = true;
            app.table_rec_GREIT_results.Enable = 'off';
            app.IMDLControlPanel = uipanel(app.SolveInverseTabGrid);
            app.IMDLControlPanel.Title = 'Inverse solver parameters';
            app.IMDLControlPanelGrid = uigridlayout(app.IMDLControlPanel);
            app.dD_imdl_solve = uidropdown(app.IMDLControlPanelGrid);
            app.dD_imdl_solve.Items = {'eidors_default', 'inv_solve_TSVD', 'inv_solve_TV_irls', 'inv_solve_TV_pdipm', 'inv_solve_abs_GN_constrain', 'inv_solve_abs_GN_prior', 'inv_solve_abs_annealingMetropolis_params', 'inv_solve_abs_annealingSimplex_params', 'inv_solve_abs_pdipm', 'inv_solve_backproj', 'inv_solve_cg', 'inv_solve_conj_grad', 'inv_solve_core', 'inv_solve_diff_GN_one_step', 'inv_solve_diff_kalman', 'inv_solve_diff_pdipm', 'inv_solve_gn', 'inv_solve_mcmc', 'inv_solve_time_prior', 'inv_solve_trunc_iterative '};
            app.dD_imdl_solve.Value = 'eidors_default';
            app.SolveLabel_2 = uilabel(app.IMDLControlPanelGrid);
            app.SolveLabel_2.Text = 'Solve';
            app.dD_imdl_RtR_prior = uidropdown(app.IMDLControlPanelGrid);
            app.dD_imdl_RtR_prior.Items = {'eidors_default', 'prior_TV', 'prior_covar', 'prior_exponential_covar', 'prior_gaussian_HPF', 'prior_gaussian_likelihood', 'prior_laplace', 'prior_laplace_old', 'prior_movement', 'prior_noser', 'prior_tikhonov', 'prior_time_smooth', ''};
            app.dD_imdl_RtR_prior.Value = 'eidors_default';
            app.RtRpriorLabel = uilabel(app.IMDLControlPanelGrid);
            app.RtRpriorLabel.Text = 'RtR prior';
            app.dD_imdl_hyperparam_func = uidropdown(app.IMDLControlPanelGrid);
            app.dD_imdl_hyperparam_func.Items = {'NOT USED', 'aa_calc_noise_figure', ''};
            app.dD_imdl_hyperparam_func.Enable = 'off';
            app.dD_imdl_hyperparam_func.Value = 'NOT USED';
            app.HyperparameterfuncLabel = uilabel(app.IMDLControlPanelGrid);
            app.HyperparameterfuncLabel.Enable = 'off';
            app.HyperparameterfuncLabel.Text = 'Hyperparameter func';
            app.dD_imdl_reconst_type = uidropdown(app.IMDLControlPanelGrid);
            app.dD_imdl_reconst_type.Items = {'difference', 'static'};
            app.dD_imdl_reconst_type.Value = 'difference';
            app.ReconstructiontypeLabel = uilabel(app.IMDLControlPanelGrid);
            app.ReconstructiontypeLabel.Text = 'Reconstruction type';
            app.dD_imdl_hyperparam_val = uieditfield(app.IMDLControlPanelGrid, 'numeric');
            app.dD_imdl_hyperparam_val.Value = 0.01;
            app.HyperparametervalueLabel = uilabel(app.IMDLControlPanelGrid);
            app.HyperparametervalueLabel.Text = 'Hyperparameter value';
            app.dD_imdl_jac_bkgnd_val = uieditfield(app.IMDLControlPanelGrid, 'numeric');
            app.dD_imdl_jac_bkgnd_val.Value = 1;
            app.JacobianbkgndvalueLabel = uilabel(app.IMDLControlPanelGrid);
            app.JacobianbkgndvalueLabel.Text = 'Jacobian bkgnd value';
            app.dD_imdl_hyperparam_params = uidropdown(app.IMDLControlPanelGrid);
            app.dD_imdl_hyperparam_params.Items = {'NOT USED', 'noise_figure', ''};
            app.dD_imdl_hyperparam_params.Enable = 'off';
            app.dD_imdl_hyperparam_params.Value = 'NOT USED';
            app.HyperparameterparametersLabel = uilabel(app.IMDLControlPanelGrid);
            app.HyperparameterparametersLabel.Enable = 'off';
            app.HyperparameterparametersLabel.Text = 'Hyperparameter parameters';
            app.dD_imdl_jac_bkgnd_func = uidropdown(app.IMDLControlPanelGrid);
            app.dD_imdl_jac_bkgnd_func.Items = {'NOT USED'};
            app.dD_imdl_jac_bkgnd_func.Enable = 'off';
            app.dD_imdl_jac_bkgnd_func.Value = 'NOT USED';
            app.JacobianbkgndfuncLabel = uilabel(app.IMDLControlPanelGrid);
            app.JacobianbkgndfuncLabel.Enable = 'off';
            app.JacobianbkgndfuncLabel.Text = 'Jacobian bkgnd func';
            app.txt_imdl_name = uieditfield(app.IMDLControlPanelGrid, 'text');
            app.txt_imdl_name.Value = 'imdl_name';
            app.NameEditFieldLabel = uilabel(app.IMDLControlPanelGrid);
            app.NameEditFieldLabel.Text = 'Name';
            app.AIDatasetTab = uitab(app.TabMain);
            app.AIDatasetTab.Tooltip = {''};
            app.AIDatasetTab.Title = 'AI Dataset';
            app.AIDatasetTabGrid = uigridlayout(app.AIDatasetTab);
            app.GenerationDatasetPanel = uipanel(app.AIDatasetTabGrid);
            app.GenerationDatasetPanelGrid = uigridlayout(app.GenerationDatasetPanel);
            app.pB_dataset_samples_Plot = uibutton(app.GenerationDatasetPanelGrid, 'push');
            app.pB_dataset_samples_Plot.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_samples_PlotButtonPushed, true);
            app.pB_dataset_samples_Plot.Text = 'Plot Samples';
            app.txt_dataset_name = uieditfield(app.GenerationDatasetPanelGrid, 'text');
            app.txt_dataset_name.Value = 'Loaded_dataset_name';
            app.pB_dataset_Load = uibutton(app.GenerationDatasetPanelGrid, 'push');
            app.pB_dataset_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_LoadPushed, true);
            app.pB_dataset_Load.Text = 'Load dataset';
            app.LoadeddatasetcompleteLabel = uilabel(app.GenerationDatasetPanelGrid);
            app.LoadeddatasetcompleteLabel.Text = 'Loaded dataset complete';
            app.pB_dataset_Generate = uibutton(app.GenerationDatasetPanelGrid, 'push');
            app.pB_dataset_Generate.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_GeneratePushed, true);
            app.pB_dataset_Generate.Text = 'Generate Dataset';
            app.led_dataset_complete = uilamp(app.GenerationDatasetPanelGrid);
            app.txt_dataset_idx_samples_to_plot = uieditfield(app.GenerationDatasetPanelGrid, 'text');
            app.txt_dataset_idx_samples_to_plot.Tooltip = {'If sample idx are loaded, these index anre the index of the indexes loaded '};
            app.txt_dataset_idx_samples_to_plot.Value = '1,2,3';
            app.SamplestoplotLabel = uilabel(app.GenerationDatasetPanelGrid);
            app.SamplestoplotLabel.Text = 'Samples # to plot';
            app.pB_dataset_samples_rec = uibutton(app.GenerationDatasetPanelGrid, 'push');
            app.pB_dataset_samples_rec.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_samples_recPushed, true);
            app.pB_dataset_samples_rec.Text = 'Reconstruct Samples';
            app.chB_dataset_samples_rec_extract_elem_data = uicheckbox(app.GenerationDatasetPanelGrid);
            app.chB_dataset_samples_rec_extract_elem_data.Text = 'Extract elem_data';
            app.chB_dataset_samples_load_idx = uicheckbox(app.GenerationDatasetPanelGrid);
            app.chB_dataset_samples_load_idx.Tooltip = {'Attention: idx in file should be zero-based!'};
            app.chB_dataset_samples_load_idx.Text = 'load "zero-based" idx from file';
            app.DatasetInputPanel = uipanel(app.AIDatasetTabGrid);
            app.DatasetInputPanel.Title = 'Dataset input data';
            app.DatasetInputPanelGrid = uigridlayout(app.DatasetInputPanel);
            app.pB_dataset_user_entry_Readme = uibutton(app.DatasetInputPanelGrid, 'push');
            app.pB_dataset_user_entry_Readme.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_user_entry_ReadmeButtonPushed, true);
            app.pB_dataset_user_entry_Readme.Tooltip = {''};
            app.pB_dataset_user_entry_Readme.Text = 'Readme';
            app.table_dataset_user_entry = uitable(app.DatasetInputPanelGrid);
            app.table_dataset_user_entry.ColumnName = {'name'; 'num_training'; 'medium conduct'; 'object typ'; 'object n'; 'object dim'; 'object conduct'; 'SNR'; 'single data n'; 'samples size'};
            app.table_dataset_user_entry.RowName = {};
            app.table_dataset_user_entry.ColumnEditable = true;
            app.pB_dataset_user_entry_Save = uibutton(app.DatasetInputPanelGrid, 'push');
            app.pB_dataset_user_entry_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_user_entry_SavePushed, true);
            app.pB_dataset_user_entry_Save.Text = 'Save dataset user_entry';
            % Show the figure after all components are created
            app.EITSoftware.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FwdModel_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EITSoftware)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EITSoftware)
        end
    end
end